classdef TP2_GUI_v5_3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        KhEditField                    matlab.ui.control.NumericEditField
        KhEditFieldLabel               matlab.ui.control.Label
        KvEditField                    matlab.ui.control.NumericEditField
        KvEditField_2Label             matlab.ui.control.Label
        Walk1mButton                   matlab.ui.control.Button
        GetSignSnapshotButton          matlab.ui.control.Button
        MethodDropDown                 matlab.ui.control.DropDown
        MethodDropDownLabel            matlab.ui.control.Label
        GetRobotPosButton              matlab.ui.control.Button
        DilateMapEditField             matlab.ui.control.NumericEditField
        DilateMapEditFieldLabel        matlab.ui.control.Label
        LookAheadEditField             matlab.ui.control.NumericEditField
        LookAheadEditFieldLabel        matlab.ui.control.Label
        MaxAngVelocityEditField        matlab.ui.control.NumericEditField
        MaxAngVelocityEditFieldLabel   matlab.ui.control.Label
        LinearVelocityEditField        matlab.ui.control.NumericEditField
        LinearVelocityEditFieldLabel   matlab.ui.control.Label
        connDistEditField              matlab.ui.control.NumericEditField
        connDistEditFieldLabel         matlab.ui.control.Label
        NumNodesEditField              matlab.ui.control.NumericEditField
        NumNodesEditFieldLabel         matlab.ui.control.Label
        MoveMPButton                   matlab.ui.control.Button
        OffsetWPEditField              matlab.ui.control.NumericEditField
        OffsetWPEditFieldLabel         matlab.ui.control.Label
        FileNameEditField              matlab.ui.control.EditField
        FileNameEditFieldLabel         matlab.ui.control.Label
        LoadColorCSVButton             matlab.ui.control.Button
        SaveColor2CSVButton            matlab.ui.control.Button
        passwordEditField              matlab.ui.control.EditField
        passwordEditFieldLabel         matlab.ui.control.Label
        userEditField                  matlab.ui.control.EditField
        userEditFieldLabel             matlab.ui.control.Label
        ImageDropDown                  matlab.ui.control.DropDown
        ImageDropDownLabel             matlab.ui.control.Label
        LoadSnapshotButton             matlab.ui.control.Button
        SaveSnapshotButton             matlab.ui.control.Button
        USBCamConnectButton            matlab.ui.control.StateButton
        GetSnapshotButton              matlab.ui.control.Button
        RobotControlLabel              matlab.ui.control.Label
        GenMultipleWPButton            matlab.ui.control.Button
        MoveSPButton                   matlab.ui.control.Button
        GenSingleWPButton              matlab.ui.control.Button
        GetRatioButton                 matlab.ui.control.Button
        CamSourceDropDown              matlab.ui.control.DropDown
        CamSourceDropDownLabel         matlab.ui.control.Label
        CameraParametersLabel          matlab.ui.control.Label
        ConnectRobotButton             matlab.ui.control.StateButton
        toAdjustApplyDropDown          matlab.ui.control.DropDown
        toDropDownLabel                matlab.ui.control.Label
        WheelBaseEditField             matlab.ui.control.NumericEditField
        WheelBaseEditFieldLabel        matlab.ui.control.Label
        WheelRadiusEditField           matlab.ui.control.NumericEditField
        WheelRadiusEditFieldLabel      matlab.ui.control.Label
        RobotParametersLabel           matlab.ui.control.Label
        camIPEditField                 matlab.ui.control.EditField
        camIPEditFieldLabel            matlab.ui.control.Label
        WP_1                           matlab.ui.control.DropDown
        WP_2                           matlab.ui.control.DropDown
        WP_3                           matlab.ui.control.DropDown
        WP_4                           matlab.ui.control.DropDown
        Label_4                        matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        WaypointOrderLabel             matlab.ui.control.Label
        MapGenerationParametersLabel   matlab.ui.control.Label
        MapCreationButton              matlab.ui.control.Button
        RefmmEditField                 matlab.ui.control.NumericEditField
        RefmmEditFieldLabel            matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        PreviewTab                     matlab.ui.container.Tab
        Preview                        matlab.ui.control.UIAxes
        HSVTopTab                      matlab.ui.container.Tab
        HSVView                        matlab.ui.control.UIAxes
        HSVMaskTab                     matlab.ui.container.Tab
        HSVMask                        matlab.ui.control.UIAxes
        MapTab                         matlab.ui.container.Tab
        MapView                        matlab.ui.control.UIAxes
        RobotPosTab                    matlab.ui.container.Tab
        RobotPos                       matlab.ui.control.UIAxes
        WaypointsTab                   matlab.ui.container.Tab
        Pos4                           matlab.ui.control.UIAxes
        Pos3                           matlab.ui.control.UIAxes
        Pos2                           matlab.ui.control.UIAxes
        Pos1                           matlab.ui.control.UIAxes
        PathTab                        matlab.ui.container.Tab
        PathView                       matlab.ui.control.UIAxes
        EdgeCutTab                     matlab.ui.container.Tab
        EdgeCut                        matlab.ui.control.UIAxes
        BoundBoxTab                    matlab.ui.container.Tab
        BoundingBoxView                matlab.ui.control.UIAxes
        ConfidenceTab                  matlab.ui.container.Tab
        Confidence                     matlab.ui.control.UIAxes
        IPCamConnectButton             matlab.ui.control.StateButton
        camIDDropDown                  matlab.ui.control.DropDown
        CameraLamp                     matlab.ui.control.Lamp
        StartStopProcessingButton      matlab.ui.control.StateButton
        ConfidentButton                matlab.ui.control.Button
        ConfidenceLevelEditField       matlab.ui.control.NumericEditField
        ConfidenceLevelEditFieldLabel  matlab.ui.control.Label
        TraficSignDropDown             matlab.ui.control.DropDown
        TraficSignDropDownLabel        matlab.ui.control.Label
        SavetoFileButton               matlab.ui.control.Button
        ApplicationControlsLabel       matlab.ui.control.Label
        BoundingBoxParametersLabel     matlab.ui.control.Label
        CircularityEditField           matlab.ui.control.NumericEditField
        CentroidEditField              matlab.ui.control.NumericEditField
        SolidityEditFieldLabel         matlab.ui.control.Label
        CentroidEditFieldLabel         matlab.ui.control.Label
        MinorAxisLengthEditField       matlab.ui.control.NumericEditField
        MinorAxisLengthEditFieldLabel  matlab.ui.control.Label
        MajorAxisLengthEditField       matlab.ui.control.NumericEditField
        MajorAxisLengthEditFieldLabel  matlab.ui.control.Label
        ExtentEditField                matlab.ui.control.NumericEditField
        ExtentEditFieldLabel           matlab.ui.control.Label
        EccentricityEditField          matlab.ui.control.NumericEditField
        EccentricityEditFieldLabel     matlab.ui.control.Label
        SolidityEditField              matlab.ui.control.NumericEditField
        HSVThresholdParametersLabel    matlab.ui.control.Label
        EdgeDetectionThresholdEditField  matlab.ui.control.NumericEditField
        EdgeDetectionThresholdEditFieldLabel  matlab.ui.control.Label
        EdgeDetectionMethodDropDown    matlab.ui.control.DropDown
        EdgeDetectionMethodDropDownLabel  matlab.ui.control.Label
        SSlider                        matlab.ui.control.Slider
        HSlider                        matlab.ui.control.Slider
        VSlider                        matlab.ui.control.Slider
        HueTolEditField                matlab.ui.control.NumericEditField
        SatTolEditField                matlab.ui.control.NumericEditField
        ValTolEditField                matlab.ui.control.NumericEditField
        ValTolEditFieldLabel           matlab.ui.control.Label
        SatTolEditFieldLabel           matlab.ui.control.Label
        HueTolEditFieldLabel           matlab.ui.control.Label
        VSliderLabel                   matlab.ui.control.Label
        SSliderLabel                   matlab.ui.control.Label
        HSliderLabel                   matlab.ui.control.Label
        CircularityEditFieldLabel      matlab.ui.control.Label
        AreaEditField                  matlab.ui.control.NumericEditField
        AreaEditFieldLabel             matlab.ui.control.Label
        camIDDropDownLabel             matlab.ui.control.Label
        ApplyCalibrationButton         matlab.ui.control.Button
        forColorDropDown               matlab.ui.control.DropDown
        GInputCalibrationButton        matlab.ui.control.Button
        forColorDropDownLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        pathToolBox= ['.\libs\spatialmath-matlab-master'];
        pathToolBox2= ['.\libs\robotics-toolbox-matlab-master'];
        pathToolBox3= ['.\libs\mobile-robotics-simulation'];
        
        ipCam; % IP Video object
        vid;   % Video Camera object
        timerObj; 
        camID = 0;
        
        %% Sign Calibration Variables
        testHSV = [0.98, 0.75, 0.75];     % Default to RED
        redHSV = [0.98, 0.75, 0.75];      % Default Red HSV values
        yellowHSV = [0.16, 0.7, 0.85];    % Default Yellow HSV values
        blueHSV = [0.61, 0.7, 0.7];       % Default Blue HSV values
        blackHSV = [0, 0, 0.2];           % Default Black HSV values
        whiteHSV = [0, 0.3, 0.3];         % Default White HSV values

        colorList = ["Red", "Blue", "Yellow", "Black", "White"];
        
        % Map Creation details
        map;
        testSnapshot;
        
        %% MAP Calibration variables
        ratio = 0.00;
        ratioHSV = [0.75, 0.2, 0.7];
        mapHSV = [0, 0.3, 0.35];

        rBaseHSV = [0, 0.65, 0.45];
        gBaseHSV = [0.35, 0.40, 0.4];
        bBaseHSV = [0.62, 0.6, 0.6];        
        yBaseHSV = [0.2, 0.65, 0.8];
       
        baseColors;
        valueSet;

        baseColorMap;

        path;

        %% Robo Config
        myLego;
        robotKinematics;
        motorEsq;
        motorDir;
   
        % signType = ["Information" "Danger" "Forbid" "Obligation" "Control"]
        % availableSign = {["DeadEnd" "Semaphore" "Bump"] ["Snow" "Bump"] ["Traffic" "Overtaking"] ["TurnRight" "TurnLeft" "Roundabout"] ["RoW"] };
        % trafficSigns = dictionary(signType, availableSign);
        
        basePossiblities
    end % Properties


    methods (Access = private)   
    %% ###############################################################
        %% brief UPDATE VIDEO FRAME
        % Gets the frame form the camera object, calls the necessary
        % processing to do to each frame depending on the selected tab in
        % the app
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function updateVideoFrame(app, ~, ~)
            IM = app.getSnapshot();                      % Acquire a frame
            % HSV = rgb2hsv(IM);
            IM = imgaussfilt(IM, 1);
            IM_double = double(IM);
            
            grIM = rgb2gray(IM);                                % Grayscale the image
            % mask = zeros(size(grIM));
            % mask(grIM > 75) = 1;
            bwIM = filterBinImg(app, grIM, "Shape");

            croppedRGB = zeros(size(IM_double));
            croppedRGB(:,:,1) = IM_double(:,:,1).*bwIM;
            croppedRGB(:,:,2) = IM_double(:,:,2).*bwIM;
            croppedRGB(:,:,3) = IM_double(:,:,3).*bwIM;
            
            cropHSV = rgb2hsv(uint8(croppedRGB));
            % cropHSV(:,:,2) = histeq(cropHSV(:,:,2));
            % cropHSV(:,:,3) = histeq(cropHSV(:,:,3));

            % Process and Show the selected tab content only
            currentTab = app.TabGroup.SelectedTab;              % Identify which tab is currently selected
            if currentTab ==  app.EdgeCutTab                    % View only the tab for the cropping mask to debug the shape filter
                imshow(bwIM, 'Parent', app.EdgeCut);
                title(app.EdgeCut, 'EdgeCut');
    
            elseif currentTab == app.HSVTopTab                     % View only the tab for the HSV converted crop to debug how colors are detected
                imshow(rgb2hsv(IM), 'Parent', app.HSVView);
                title(app.HSVView, 'HSV');

            elseif currentTab == app.MapTab                     % View only the tab for the HSV converted crop to debug how colors are detected
                cropLAB = rgb2lab(uint8(croppedRGB)); 
                imshow(cropLAB, 'Parent', app.MapView);
                title(app.MapView, 'LAB');

            elseif currentTab == app.HSVMaskTab                 % View only the tab for the HSV converted crop MASK to debug the mask creation before identifying shape
                maskHSV = app.idColor(cropHSV, "TEST");
                %maskHSV = imfill(maskHSV, 'holes');
                imshow(maskHSV, 'Parent', app.HSVMask);
                title(app.HSVMask, 'HSV');

            elseif currentTab == app.BoundBoxTab                % View the result of all the processing that occurs
                annotated_Frame = app.processBlobs(IM, cropHSV);
                imshow(annotated_Frame, 'Parent', app.BoundingBoxView);
                title(app.BoundingBoxView, 'Detected Shapes');

            elseif currentTab == app.ConfidenceTab
                %Will have a graph showing confidence results
            end % if currentTab
        end % updateVideoFrame

    %% ###############################################################
        %% brief filterBinImg
        % Clean the image. Shape is for the original frame to be cropped
        % based on the Edge detection algorithm. the Method and threshold
        % can be changed in the app Live for verifying best results

        % Color was being used but it isn't currently
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function filteredImg = filterBinImg(app, binIM, whatToFilter)
            if whatToFilter == "Shape"
                % 1. Detect Edges
                filteredImg = edge(binIM,app.EdgeDetectionMethodDropDown.Value, app.EdgeDetectionThresholdEditField.Value);
                % 2. Close gaps in the edges
                filteredImg = imclose(filteredImg,strel('line',5,3)); 
                % 3. Fill the shape
                filteredImg = imfill(filteredImg,'holes'); 
                % se = strel('disk', 2); 
                % filteredImg = imdilate(filteredImg, se);
                filteredImg = imopen(filteredImg,strel(ones(2,2)));
                % 5. Remove small noise
                filteredImg = bwareaopen(filteredImg,3000);
            elseif whatToFilter == "Color"
                filteredImg = app.imageFilter(binIM, {"dilate", 4; "erode", 4; "areaopen", 10}) ;
                % se = strel('disk', 4);
                % filteredImg = imdilate(binarymask, se);% Tapa pequenos buracos
                % filteredImg= imerode(filteredImg, se);% Remove ruído externo
                % filteredImg = bwareaopen(filteredImg, 10);% Remove objetos muito pequenos/ruído que sobrou
            end
        end

    %% ###############################################################
        %% brief processBlobs
        % Goes through every color required creating a mask from the cropped image. This mask is
        % used to detect every blob with that color to identify it. Visibility of the blob is
        % limited by the Area Field in the app
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function annotatedImage = processBlobs(app, image, croppedImg)
            % Annotate the OG image with the color and shape classification
            annotatedImage = image;
   
            smallWhiteObjMask = app.idColor(croppedImg, "White");
            smallBlackObjMask = app.idColor(croppedImg, "Black");

            for color=1:numel(app.colorList)
                label = app.colorList(color);
                if label == "Red"
                    colorMask = app.idColor(croppedImg, label);
                    invertedMask = ~colorMask & ~smallWhiteObjMask & smallBlackObjMask;
                elseif label == "Blue"
                    colorMask = app.idColor(croppedImg, label);
                    invertedMask = ~colorMask & smallWhiteObjMask & ~smallBlackObjMask;
                elseif label == "Yellow"
                    colorMask = app.idColor(croppedImg, label);
                    invertedMask = ~colorMask & smallBlackObjMask;
                elseif label == "Black"
                    continue;
                    % colorMask = app.idColor(croppedImg, label);
                elseif label == "White"
                    continue;
                end          
               
                filledColorMask = imfill(colorMask, 'holes');
                props = regionprops(filledColorMask, 'Solidity', 'Area', 'Eccentricity', 'MajorAxisLength', 'MinorAxisLength', 'Extent', 'Circularity', 'Centroid', 'BoundingBox');
                                
                if isempty(props)
                    continue;
                end
                
                for blob = 1:numel(props)
                    if props(blob).Area > app.AreaEditField.Value
                        shapeLabel = app.idShape(props(blob)); 
                        % myLabel = label + " " + shapeLabel;
                        
                        smallProps = regionprops(invertedMask, 'Area', 'Centroid');
                        [~, idx] = max([smallProps.Area]); % Get the biggest of the small objects
                        
                        myLabel = app.idSign(label, shapeLabel, numel(smallProps), smallProps(idx).Centroid, props(blob).BoundingBox);
                        if myLabel  ~= "None"
                            % Annotate the image with the bounding box and combined label (e.g., "Red Circle")
                            annotatedImage = insertObjectAnnotation(annotatedImage, 'rectangle', props(blob).BoundingBox, myLabel, ...
                            'LineWidth', 3, 'Color', label, 'FontSize', 18, 'TextColor', 'black');
                        end % if label
                    end % if Area 
                end % for blob in props
            end % for color

        end % Process Blobs End
        

    %% ###############################################################
        %% brief idSign - TBM
        % Get the shape detected and classify it
        % Is called for every blob detected in process blobs after the color mask is created 
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function signLabel = idSign(app, colorLabel, shapeLabel, numSmallProps, childPropCentroid, parentPropBbox)
            disp ("Number of Small Props: " + numSmallProps);
            if shapeLabel == "Circle"
                if colorLabel == "Red"
                    signLabel = "Forbid";
                    if numSmallProps > 0
                        signLabel = signLabel + " Overtake";
                    else
                        signLabel = signLabel + " Traffic";
                    end
                elseif colorLabel == "Blue"
                    signLabel = "Obligation";
                    if numSmallProps > 3
                        signLabel = signLabel + "  Roundabout";
                    else
                        bboxCenterX = parentPropBbox(1) + parentPropBbox(3)/2;
                        symbol = childPropCentroid(1);
                        if symbol > bboxCenterX
                            signLabel = signLabel + " Turn Right";
                        else
                            signLabel = signLabel + " Turn Left";
                        end
                    end
                else
                    signLabel = "None";
                end
            elseif shapeLabel == "Square"
                if colorLabel == "Blue"
                    signLabel = "Dead end";
                else
                    signLabel = "None";
                end
            elseif shapeLabel == "Triangle"
                if numSmallProps <= 1
                    signLabel = "Right of Way";
                else
                    signLabel = "Danger";
                end
                
            elseif shapeLabel == "Rhombus"
                signLabel = "Temporary";
            else
                signLabel = "None";
            end %if shape end
        end

    %% ###############################################################        
        %% brief idSHAPE
        % Get the shape detected and classify it
        % Is called for every blob detected in process blobs after the color mask is created 
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function  shapeType = idShape(app, prop)
            % Get center of Bounding Box
            bboxCenter = [prop.BoundingBox(1) + prop.BoundingBox(3)/2, prop.BoundingBox(2) + prop.BoundingBox(4)/2];
            offset = norm(prop.Centroid - bboxCenter);

            % Calculate Aspect Ratio (AR) to determine if square or
            % rectangle
            if prop.MinorAxisLength > 0
                AR = prop.MajorAxisLength / prop.MinorAxisLength;
            else
                AR = Inf; % Avoid division by zero
            end
            
            % 1. Circle Check: Low Eccentricity (close to 0) and High Solidity (close to 1)
            if prop.Circularity > 0.9 && prop.Eccentricity < 0.25
                shapeType = "Circle";
                
            % 2. Square Check: Low Eccentricity, high Solidity, and Aspect Ratio close to 1 (Major/Minor axis almost equal)
            elseif (prop.Extent > 0.9) && (AR > 0.85 && AR < 1.15) 
                shapeType = "Square";
        
            % 3. Rectangle Check: Higher Eccentricity allowed, high Solidity (solid region)
            elseif (prop.Extent > 0.9) && (AR > 1.7)
                % Distinguish Rhombus/Rectangle based on extent (how well the shape fills its bounding box)
                % Note: A general rectangle is defined by eccentricity > 0.4 and < 0.9 (elongated, but not a line)
                shapeType = "Rectangle";
                
            % 4. Triangle Check: Lower Solidity and Extent often indicate non-rectangular/non-circular shapes.
            % A triangle will have Solidity around 0.5 (Area / BoundingBox Area) and low Extent.
            % This is a complex distinction, but we use Solidity and Aspect Ratio as heuristics.
            elseif offset > 10
                shapeType = "Triangle";
                
            % 5. Rhombus (Approximation): Similar to a square but with lower Extent due to rotation
            elseif offset < 10
                shapeType = "Rhombus";
                
            else
                shapeType = "Other";
            end 

            % if prop.Area > 2000
            %     fprintf('Shape: %s \t\t(Circularity: %.2f, \tEccentricity: %.2f, \tAR: %.2f, \tExtent: %.2f, \tOffset: %.2f )\n', ...
            %     shapeType, prop.Circularity, prop.Eccentricity, AR, prop.Extent, offset);
            % end
        end % idShape

    %% ###############################################################   
        %% brief
        % Obtain a binary mask based on HSV values for Threshold
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function ColorMask = idColor(app, hsvImage, color)            
            H = hsvImage(:,:,1);
            S = hsvImage(:,:,2);
            V = hsvImage(:,:,3); 

            hTol = app.HueTolEditField.Value;
            sTol = app.SatTolEditField.Value;
            vTol = app.ValTolEditField.Value;

            % Black is only Value under 0.2
            if strcmpi(color, "Black")
                hsvArray = app.blackHSV;
                % Black is mainly low Value (V)
                blackVthreshold = hsvArray(3) + vTol;  % e.g. hsvArray(3) ~ 0.05
                ColorMask = V <= blackVthreshold;
                % ColorMask = app.filterBinImg(ColorMask, "Color");
                return;
            elseif strcmpi(color, "White")
                hsvArray = app.whiteHSV;
                ColorMask = (S < hsvArray(2)) & (V > hsvArray(3));
                return;
            elseif strcmpi(color, "Red")
                hsvArray = app.redHSV;

            elseif strcmpi(color, "Blue")
                hsvArray = app.blueHSV;

            elseif strcmpi(color, "Yellow")
                hsvArray = app.yellowHSV;

            elseif strcmpi(color, "TEST") 
                hsvArray = app.testHSV;
            end


            % Saturation and Value are within a region for every color
            % before turning white or black in HSV
            ColorMask = (S >= hsvArray(2) - sTol) & (S <= hsvArray(2) + sTol) & ...
                           (V >= hsvArray(3) - vTol) & (V <= hsvArray(3) + vTol);

            % Red is between 0-0.1 and 0.9-1 Hue values. For that reason
            % and considering tolerances, a wraparound is required to
            % handle it properly
            if hsvArray(1) - hTol < 0 
                ColorMask = ColorMask & ...
                    ((H >= 1 + (hsvArray(1) - hTol)) | (H <= hsvArray(1) + hTol));

            elseif hsvArray(1) + hTol > 1
                ColorMask = ColorMask & ...
                    ((H >= hsvArray(1) - hTol) | (H <= hsvArray(1) + hTol - 1));

            else %For every other color other than Red
                ColorMask = ColorMask & ...
                    (H >= hsvArray(1) - hTol) & (H <= hsvArray(1) + hTol);
            end

            ColorMask = app.filterBinImg(ColorMask, "Color");
        end % idColor


%% ######################################################################################################################################
    %% AUX
        % Functions that assist the user getting the necessary values to
        % assist getting the ap working
%% ######################################################################################################################################
        

    %% ###############################################################
        %% brief pickColor
        % Pick Colors for calibration using the ginput function
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function hsv_vals = pickColor(app,frame,colorName)
            % 1. Criar uma figura TEMPORÁRIA (o tal "white board")
            % 'Modal' significa que ficas obrigado a clicar nela antes de voltar à App
            hFig = figure('Name', ['Calibrar ' colorName], ...
                          'NumberTitle', 'off', ...
                          'MenuBar', 'none', ...
                          'ToolBar', 'none', ...
                          'WindowStyle', 'modal'); 
            
            % 2. Mostrar a imagem nessa nova janela IMEDIATAMENTE
            imshow(frame);
            title(['Press On ' colorName], 'FontSize', 15, 'Color', 'red', 'FontWeight', 'bold');
            
            % 3. Usar o ginput nessa janela
            try
                [x, y] = ginput(5); % Espera pelo teu clique
                
                % Fecha a janela assim que clicares
                close(hFig);
                
                % --- PROCESSAMENTO DOS 3 CLIQUES ---
                h_total = 0;
                s_total = 0;
                v_total = 0;
                valid_clicks = 0;
                
                for k = 1:5
                    % --- Processar o clique (igual a antes) ---
                    col = round(x(k));
                    row = round(y(k));
                    
                    % Proteção para não dar erro se clicar fora
                    if col < 1 || col > size(frame,2) || row < 1 || row > size(frame,1)
                        hsv_vals = [0 0 0];
                        return;
                    end
                    
                    % Média 5x5 à volta do clique
                    c_min = max(1, col-2); c_max = min(size(frame,2), col+2);
                    r_min = max(1, row-2); r_max = min(size(frame,1), row+2);
                    
                    roiHSV = frame(r_min:r_max, c_min:c_max, :);
                    % roiHSV = rgb2hsv(roi);
                    
                    h_total = h_total + mean(roiHSV(:,:,1), 'all');
                    s_total = s_total + mean(roiHSV(:,:,2), 'all');
                    v_total = v_total + mean(roiHSV(:,:,3), 'all');
                    
                    disp(['H_Total: ', num2str(h_total)]);
                    disp(['S_Total: ', num2str(s_total)]);
                    disp(['V_Total: ', num2str(v_total)]);

                    valid_clicks = valid_clicks + 1;
                end
                       
                if valid_clicks > 0
                    h_avg = h_total / valid_clicks;
                    s_avg = s_total / valid_clicks;
                    v_avg = v_total / valid_clicks;
                    disp(['Cor: ', colorName]); 
                    disp(['H_Value: ', num2str(h_avg)]);
                    disp(['S_Value: ', num2str(s_avg)]);
                    disp(['V_Value: ', num2str(v_avg)]);
                    hsv_vals = [h_avg, s_avg, v_avg];
                    else
                    % Se todos os cliques foram fora da imagem
                    hsv_vals = [0 0 0];
                end

            catch
                % Se fechares a janela sem clicar
                if ishandle(hFig)
                    close(hFig);
                end
                hsv_vals = [0 0 0];
            end
        end %pickColor


    %% ###############################################################
        %% Map creation funtion
        % Receives a binary image and a reference value in mm to calibrate
        % the map that is going to be used to reference the position of
        % waypoints and the robot movement
        function mapVar = createMapVar(app, topViewBW)
            scale_metros = app.ratio * 1000;
            mapVar = binaryOccupancyMap(~topViewBW, scale_metros);
        end % mapCreation
       
%% ###############################################################
%% brief angdiff
% Calcula a diferença mínima entre dois ângulos [-pi, pi]
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function d = angdiff(~, th1, th2)
            d = th1 - th2;
            % wrap the result into the interval [-pi pi)
            d = mod(d+pi, 2*pi) - pi;
        end

%% ###############################################################
%% Drive2Point (Go-To-Goal)
function [finalPose, historicoTrajetoria] = Drive2Point(app, goalPoint, poseInicial, imagemFundo)
    % --- 1. CONFIGURAÇÃO ---
    resetRotation(app.motorEsq);
    resetRotation(app.motorDir);

    % Handle Pose input
    if nargin < 3
        currentPose = [0, 0, 0];
    else
        currentPose = double(poseInicial);
        if numel(currentPose) == 3
            currentPose = reshape(currentPose, 1, 3);
        end
    end

    historicoTrajetoria = currentPose(1:2);

    % --- TUNING GAINS (Adjust these for your surface) ---
    Kv = app.KvEditField.Value;   % Linear Gain (Reduced for smoothness)
    Kh = app.KhEditField.Value;   % Angular Gain (Stronger correction)
    
    % Thresholds
    distTolerance = 0.03;       % 3 cm tolerance
    angleThreshold = deg2rad(15); % If error > 15 deg, Turn in place
    minPower = 8;               % Minimum motor power to overcome friction

    % --- 2. SETUP GRÁFICO ---
    hold(app.PathView, 'on');

    if nargin >= 4 && ~isempty(imagemFundo)
        oldImages = findobj(app.PathView, 'Type', 'image');
        delete(oldImages);
        
        % (Existing Image Setup Code...)
        imgH_px = size(imagemFundo, 1);
        imgW_px = size(imagemFundo, 2);
        fatorConversao = app.ratio * 1000;
        W_m = imgW_px / fatorConversao;
        H_m = imgH_px / fatorConversao;
        hImg = image(app.PathView, 'CData', imagemFundo, 'XData', [0 W_m], 'YData', [H_m 0]);
        uistack(hImg, 'bottom');
        set(app.PathView, 'YDir', 'normal');
        xlim(app.PathView, [0 W_m]);
        ylim(app.PathView, [0 H_m]);
    else
        axis(app.PathView, 'equal');
        grid(app.PathView, 'on');
    end

    % Plot Objects
    hRobot = plot(app.PathView, currentPose(1), currentPose(2), 'bo', 'MarkerFaceColor', 'b', 'MarkerSize', 8);
    hPath  = plot(app.PathView, currentPose(1), currentPose(2), 'm-', 'LineWidth', 2);
    plot(app.PathView, goalPoint(1), goalPoint(2), 'rx', 'MarkerSize', 10, 'LineWidth', 3);
    drawnow;

    % --- 3. LOOP DE CONTROLO ---
    disp('A iniciar Drive2Point Melhorado...');
    sampleTime = 0.05; 

    lastRotEsq = 0;
    lastRotDir = 0;
    loopAtivo = true;

    tic;

    while loopAtivo
        loopStart = tic;

        % A. Calcular Erros
        dx = goalPoint(1) - currentPose(1);
        dy = goalPoint(2) - currentPose(2);
        distError = sqrt(dx^2 + dy^2);

        targetTheta = atan2(dy, dx);
        thetaError = app.angdiff(targetTheta, currentPose(3));

        % B. Verificar Paragem
        if distError < distTolerance
            disp('Drive2Point: Destino Alcançado!');
            break;
        end

        % C. LEI DE CONTROLO INTELIGENTE
        
        % 1. Turn-in-Place Logic:
        % If we are not facing the target (error > 15 deg), DO NOT move forward.
        if abs(thetaError) > angleThreshold
            v_ref = 0; 
            % Aggressive turning gain when static to rotate fast
            w_ref = Kh * 1.5 * thetaError; 
        else
            % 2. Smooth Drive Logic:
            % Scale linear velocity by cos(thetaError). 
            % If error is 0, speed is 100%. If error is 15 deg, speed drops slightly.
            % This prevents "Arcing" and keeps the robot on the straight line.
            v_ref = Kv * distError * cos(thetaError);
            w_ref = Kh * thetaError;
        end

        % D. Saturação
        maxV = app.LinearVelocityEditField.Value;
        maxW = app.MaxAngVelocityEditField.Value;
        
        v_ref = max(min(v_ref, maxV), -maxV);
        w_ref = max(min(w_ref, maxW), -maxW);

        % E. Enviar para Motores

        [wL_rads, wR_rads] = inverseKinematics(app.robotKinematics, v_ref, w_ref);
        radsToPower = 100 / 17.5; 
        
        pwrEsq = wL_rads * radsToPower;
        pwrDir = wR_rads * radsToPower;

        % F. Deadband / Friction Compensation
        % EV3 motors stall below ~8-10 power. We bump it up if it's non-zero.
        if abs(pwrEsq) > 0.1 && abs(pwrEsq) < minPower
            pwrEsq = minPower * sign(pwrEsq);
        end

        if abs(pwrDir) > 0.1 && abs(pwrDir) < minPower
            pwrDir = minPower * sign(pwrDir);
        end

        % Cap at 100
        pwrEsq = max(min(pwrEsq, 100), -100);
        pwrDir = max(min(pwrDir, 100), -100);

        app.motorEsq.Speed = pwrEsq;
        app.motorDir.Speed = pwrDir;
        start(app.motorEsq);
        start(app.motorDir);

        % G. Odometria
        curRotEsq = double(readRotation(app.motorEsq));
        curRotDir = double(readRotation(app.motorDir));
        elapsed = toc(loopStart);

        if elapsed == 0, elapsed = 0.001; end
            wL_meas = ((curRotEsq - lastRotEsq) * (pi/180)) / elapsed;
            wR_meas = ((curRotDir - lastRotDir) * (pi/180)) / elapsed;
    
            lastRotEsq = curRotEsq;
            lastRotDir = curRotDir;
    
            [vMeas, wMeas] = forwardKinematics(app.robotKinematics, wL_meas, wR_meas);
    
            
            currentPose(1) = currentPose(1) + (vMeas * cos(currentPose(3))) * elapsed;
            currentPose(2) = currentPose(2) + (vMeas * sin(currentPose(3))) * elapsed;
            currentPose(3) = currentPose(3) + wMeas * elapsed;
    
            historicoTrajetoria = [historicoTrajetoria; currentPose(1:2)];
    
            % H. Atualizar Gráfico
            set(hRobot, 'XData', currentPose(1), 'YData', currentPose(2));
            set(hPath, 'XData', historicoTrajetoria(:,1), 'YData', historicoTrajetoria(:,2));
            drawnow limitrate;
    
            if elapsed < sampleTime
                pause(sampleTime - elapsed);
            end
        end

        stop(app.motorEsq);
        stop(app.motorDir);
        finalPose = currentPose;
    end % Drive2Point
        
   %% ###############################################################
    %% Move Robot using Pure Pursuit
    function [finalPose, historicoTrajetoria] = PurePursuit( app, waypoints, poseInicial, imagemFundo )
        
        % --- 1. CONFIGURAÇÃO DE HARDWARE ---
        resetRotation(app.motorEsq);
        resetRotation(app.motorDir);
    
        % --- SAFETY FIX: Force currentPose to be a 1x3 ROW vector ---
        if nargin < 3
            currentPose = [0, 0, 0]; 
        else
            currentPose = double(poseInicial);
            % Force 1 row, 3 columns (x, y, theta)
            if numel(currentPose) == 3
                currentPose = reshape(currentPose, 1, 3);
            end
        end
        
        % FIX 1: Initialization must be a ROW (1x2) to match the loop later
        historicoTrajetoria = currentPose(1:2); 

    
        % Configuração do Controlador Pure Pursuit
        controller = controllerPurePursuit;
        controller.Waypoints = waypoints;
        controller.LookaheadDistance = app.LookAheadEditField.Value;      
        controller.DesiredLinearVelocity = app.LinearVelocityEditField.Value; 
        controller.MaxAngularVelocity = app.MaxAngVelocityEditField.Value;     
    
        % VERIFICA SE A IMAGEM FOI FORNECIDA
        if nargin >= 3 && ~isempty(imagemFundo)
            imshow(imagemFundo, "Parent", app.PathView);
            hold (app.PathView, 'on');
            fatorConversao = app.ratio * 1000;
            
            imgH_px = size(imagemFundo, 1);
            imgW_px = size(imagemFundo, 2);
            
            W_m = imgW_px / fatorConversao;
            H_m = imgH_px / fatorConversao;
            
            image('CData', imagemFundo, 'XData', [0 W_m], 'YData', [H_m 0], 'Parent', app.PathView);
            
            set(app.PathView, 'YDir', 'normal');
            xlim(app.PathView, [0 W_m]);
            ylim(app.PathView, [0 H_m]);
            title(app.PathView, 'Navegação (Sobreposta na Imagem)');
        else
            grid(app.PathView, 'on'); axis(app.PathView, 'equal');
            title(app.PathView, 'Navegação EV3 - Pure Pursuit');
            xlabel(app.PathView, 'X [m]'); ylabel(app.PathView, 'Y [m]');
        end
    
        % Plot dos elementos gráficos
        plot(app.PathView, waypoints(:,1), waypoints(:,2), 'ro--', 'LineWidth', 2, 'DisplayName', 'Waypoints');
        hRobot = plot(app.PathView, currentPose(1), currentPose(2), 'bo', 'MarkerFaceColor', 'b', 'MarkerSize', 8, 'DisplayName', 'Robô');
        hPath  = plot(app.PathView, currentPose(1), currentPose(2), 'c-', 'LineWidth', 2, 'DisplayName', 'Trajetória');
        legend(app.PathView, 'Location', 'best');
        
        % --- 5. LOOP DE CONTROLO ---
        disp('A iniciar movimento...');
        sampleTime = 0.05; 
        goalRadius = 0.03; 
        
        lastRotEsq = 0;
        lastRotDir = 0;
        loopAtivo = true;
        
        tic; 
        
        while loopAtivo
            loopStart = tic; 
            
            % A. Verificar Distância
            % Ensure we are subtracting Row from Row
            distToGoal = norm(currentPose(1:2) - waypoints(end,:));
            
            if distToGoal < goalRadius
                disp('Destino Alcançado!');
                break;
            end
            
            % B. Controlador
            [vRef, wRef] = controller(currentPose);
            
            % C. Cinemática Inversa
            [wL_rads, wR_rads] = inverseKinematics(app.robotKinematics, vRef, wRef);
            
            % D. Comandos Motores
            radsToPower = 100 / 17.5; 
            pwrEsq = max(min(wL_rads * radsToPower, 100), -100);
            pwrDir = max(min(wR_rads * radsToPower, 100), -100);
            
            app.motorEsq.Speed = pwrEsq;
            app.motorDir.Speed = pwrDir;
            start(app.motorEsq);
            start(app.motorDir);
            
            % E. Odometria
            curRotEsq = double(readRotation(app.motorEsq));
            curRotDir = double(readRotation(app.motorDir));
            elapsed = toc(loopStart); 
            if elapsed == 0, elapsed = 0.001; end 
            
            wL_meas = ((curRotEsq - lastRotEsq) * (pi/180)) / elapsed;
            wR_meas = ((curRotDir - lastRotDir) * (pi/180)) / elapsed;
            
            lastRotEsq = curRotEsq;
            lastRotDir = curRotDir;
            
            [vMeas, wMeas] = forwardKinematics(app.robotKinematics, wL_meas, wR_meas);
            
            currentPose(1) = currentPose(1) + (vMeas * cos(currentPose(3))) * elapsed;
            currentPose(2) = currentPose(2) + (vMeas * sin(currentPose(3))) * elapsed;
            currentPose(3) = currentPose(3) + wMeas * elapsed;
            
            % FIX 2: Vertical Concatenation [Row; Row]
            % Ensure both parts are Row vectors (1x2)
            historicoTrajetoria = [historicoTrajetoria; currentPose(1:2)];
            
            % F. Atualizar Gráfico
            set(hRobot, 'XData', currentPose(1), 'YData', currentPose(2));
            set(hPath, 'XData', historicoTrajetoria(:,1), 'YData', historicoTrajetoria(:,2));
            drawnow limitrate;
            
            if elapsed < sampleTime
                pause(sampleTime - elapsed);
            end
        end
        
        stop(app.motorEsq);
        stop(app.motorDir);
        finalPose = currentPose;
    end % PurePursuit

   %% ####################################################################    
   %% brief
        % Initialize the Robot connection with USB
        function initRobot(app)
            %% Init Robot
            if app.ConnectRobotButton.Value == true
                disp('A conectar ao EV3...');
                app.myLego = legoev3('usb');
                beep(app.myLego);           
                % --- 2. CONFIGURAÇÃO DE HARDWARE ---
                app.motorEsq = motor(app.myLego, 'A');
                app.motorDir = motor(app.myLego, 'D');
                
                % --- 3. CONFIGURAÇÃO DO ROBÔ E CONTROLADOR ---
                % Criação do objeto de cinemática da Toolbox
                app.robotKinematics = DifferentialDrive(app.WheelRadiusEditField.Value, app.WheelBaseEditField.Value);
            end
        end % initRobot 

   %% ####################################################################
   %% brief
            % Recebe imagem binaria e adquire apenas o ponto central do
            % blob encontrado na mesma
            % Assume a conversao da cor para a qual o waypoint esta a ser
            % criado antes da chamada da funcao
            function [waypointPx, waypoint] = getWaypoint( app, binIM )
            
            %% 4. Cálculos do Centroide
            props = regionprops(binIM, 'Centroid');
            
            if ~isempty(props)
                % Se houver mais que um objeto, este loop desenha em todos
                % Mas vamos focar no maior/primeiro como no teu código
                cX = props(1).Centroid(1);
                cY = props(1).Centroid(2);
                waypointPx = [cX, cY];
                % Mostra coordenadas
                % texto = sprintf('X: %.1f, Y: %.1f', cX, cY);
                % text(cX, cY - 20, texto, 'Color', 'yellow', 'FontSize', 10, 'FontWeight', 'bold');
                
                % title('Resultado Final com Centroide');
                
                imgHeight = size(binIM, 1);
                % Output para a consola (os teus cálculos mm)
                waypointX = cX / (app.ratio*1000);
                waypointY = (imgHeight - cY) / (app.ratio*1000); %%inverter o Y problema que tinhamos do Y invertido
                waypoint = [waypointX, waypointY];
                fprintf('Posição detetada: X=%.3f, Y=%.3f\n', waypointX, waypointY);
            else
                title('Nenhum objeto verde encontrado');
            end
            hold off;
        end % getWaypoint

 %% ####################################################################
 %% brief
    % Obtains the pixel to mm ratio
    function ratio = getRatio(app, binIM)
            CC = bwconncomp(binIM);
            
            %% regionprops
            props = regionprops(CC, 'Centroid');
            dx = props(1).Centroid(1) - props(2).Centroid(1);
            ratio = abs(dx / app.RefmmEditField.Value);
        end % getRatio

 %% ####################################################################
 %% brief
    % Depending on the drop down of the GUI it selects the camera to
    % take the frame. A Connect to camera buttton will work for the USB
    % camera only
        function image = getSnapshot(app)
        % 1. Clear the previous image from the UI
            cla(app.Preview); 
            cla(app.PathView);
            reset(app.Preview); % Optional: Resets axis properties like zoom/pan
            reset(app.PathView);

            image = []; % Initialize as empty to detect failure later
        
            try
                % --- IP CAMERA ---
                if app.CamSourceDropDown.Value == "IP"
                    % snapshot is blocking. If this line passes without error, 
                    % you are guaranteed a new frame.
                    image = snapshot(app.ipCam);
                    
                % --- USB CAMERA ---
                elseif app.CamSourceDropDown.Value == "USB"
                    % Check if video object exists and is valid
                    if isempty(app.vid) || ~isvalid(app.vid)
                        return;
                    end
                    
                    % Ensure the video object is actually running before triggering
                    if strcmp(app.vid.Running, 'off')
                        start(app.vid);
                        pause(0.1); % Give it a moment to warm up
                    end
        
                    trigger(app.vid); 
                    % 'vidd' was a typo, changed to 'app.vid'
                    image = getdata(app.vid, 1, 'uint8'); 
                    
                % --- STATIC FILE ---
                elseif app.CamSourceDropDown.Value == "Image"
                    filePath = "./capturas_ipcam/" + app.ImageDropDown.Value + ".jpg";
                    if exist(filePath, 'file')
                        image = imread(filePath);
                    else
                        uialert(app.UIFigure, "File not found.", "Error");
                        return;
                    end
                end
                
                % 4. Final Verification
                % If 'image' is still empty here, something went wrong logically
                if isempty(image)
                    return; 
                end
        
                % Show the new image
                imshow(image, "Parent", app.Preview);
                app.testSnapshot = image;
        
            catch ME
                % This is how you know the snapshot FAILED
                uialert(app.UIFigure, "Failed to acquire image: " + ME.message, 'Capture Error');
            end
        end % getSnapshot

 %% ####################################################################
 %% brief 
    % Assuming threshold can be seen as an array or a single value
    % depending on the data, for hsv threshold is an array [1,2,3] and
    % for gray it is a single value
        function binIM = toBIN(app, image, hsvORgray, threshold)
            switch hsvORgray
                case "hsv"      
                    hsv = rgb2hsv(image);
                    h = hsv(:,:,1);
                    s = hsv(:,:,2);
                    v = hsv(:,:,3);
                              
                    hLim_inf = threshold(1) - app.HueTolEditField.Value;
                    hLim_sup = threshold(1) + app.HueTolEditField.Value;
                    
                    sLim_inf = threshold(2) - app.SatTolEditField.Value;
                    sLim_sup = threshold(2) + app.SatTolEditField.Value;

                    vLim_inf = threshold(3) - app.ValTolEditField.Value;
                    vLim_sup = threshold(3) + app.ValTolEditField.Value;    

                    if (hLim_inf < 0)
                        hLim_inf = 1 + hLim_inf;
                        hueMask = (h > hLim_inf) | (h < hLim_sup);
                    elseif (hLim_sup > 1)
                        hLim_sup = hLim_sup - 1;
                        hueMask = (h > hLim_inf) | (h < hLim_sup);
                    else 
                        % Standard Case: Hue is in the middle (e.g., Green/Blue)
                        hueMask = (h > hLim_inf) & (h < hLim_sup);
                    end

                    mask = false(size(image, 1), size(image, 2));
                    mask(hueMask & (s > sLim_inf & s < sLim_sup) ...
                                 &  (v > vLim_inf & v < vLim_sup)) = 1; 

                    binIM = mask;


                    % % Filter small objects detected
                    % se = strel('disk', 4);
                    % binIM = imdilate(binIM, se);     % Tapa pequenos buracos
                    % binIM = imerode(binIM, se);      % Remove ruído externo
                    % binIM = bwareaopen(binIM, 10);   % Remove objetos muito pequenos/ruído que sobrou

                case "gray"
                    maskGray = false(size(image, 1), size(image, 2));
                    maskGray (gray < threshold) = 1;
  
                    binIM = app.imageFilter(maskGray, {"erode", 4; "dilate", 4; "areaopen", 10; "dilate", 20});

                
                case "hsv_map" 
                    %% 1. Converter para HSV
                    hsv = rgb2hsv(image);
                    s = hsv(:,:,2); % Canal Saturação (bom para detetar cores)
                    v = hsv(:,:,3); % Canal Value (bom para detetar preto)
                
                    %% 2. Detetar as Bases Coloridas
                    % O chão branco tem saturação quase 0. As cores vivas têm saturação alta.
                    % Limiar de 0.25 costuma funcionar bem para papéis coloridos.
                    maskCores = s > threshold(2); 
                
                    %% 3. Detetar Preto (Bordas residuais ou base preta)
                    maskPreto = v < threshold(3);
                
                    %% 4. Combinar Obstáculos
                    obstacles = maskCores | maskPreto;
                    obstacles = app.imageFilter(obstacles, {"areaopen", 2000; "close", 10; "dilate", app.DilateMapEditField.Value});
                        
                    % % %% 5. Limpeza de Ruído
                    % % % Remove "poeira" ou ruído pequeno (menos de 200 pixeis)
                    % obstacles = bwareaopen(obstacles, 200);
                    % 
                    % % Torna os blocos sólidos (fecha buracos dentro das cores)
                    % obstacles = imclose(obstacles, strel('disk', 10));
                    % 
                    % % Dilatar ligeiramente os obstáculos para dar uma margem de segurança ao robô
                    % % (Opcional: aumenta se o robô bater nas quinas)
                    % obstacles = imdilate(obstacles, strel('disk', 5));
                
                    binIM = ~obstacles;
            end % switch
        end % toBIN
        
        function croppedImage = cropArena(app, image)
            % grayBIN = app.toBIN(image, 'gray', 60);
            grayBIN = rgb2gray(image);
            limiarPreto = 60; % Valor agressivo para apanhar só a fita e cabos
            maskGray = grayBIN < limiarPreto;    
            %% 3. Fechar a "Cerca" (Crucial!)
            % Se houver falhas na fita preta (reflexo ou desgaste), o flood fill vai
            % "vazar" para fora. Vamos dilatar as bordas para fechar buracos.
            se = strel('disk', 8); 
            dilatedGrayBIN= imdilate(maskGray, se);
            
            %% 4. Inverter a Lógica -> Achar o Chão
            % Agora, tudo o que é preto vira 0, tudo o que é chão vira 1.
            binIM = ~dilatedGrayBIN;
            
            %% 5. Selecionar a Arena (O objeto no Centro)
            % Assumimos que a arena está +/- no meio da foto.
            centroY = round(size(image, 1) / 2);
            centroX = round(size(image, 2) / 2);
            
            % bwselect seleciona o objeto branco que toca no ponto (X,Y)
            maskArena = bwselect(binIM, centroX, centroY, 4);
            
            % Se por acaso o centro estiver tapado por um bloco colorido, o bwselect
            % pode falhar. Salvaguarda: usar o maior objeto branco.
            if sum(maskArena(:)) == 0
                maskArena = bwareaopen(binIM, 5000); % Tira ruído
                maskArena = bwpropfilt(maskArena, 'Area', 1); % Pega no maior
            end
            
            %% 6. Preencher o conteúdo da Arena
            % Agora temos o chão branco, mas os blocos coloridos e a sombra forte
            % podem ter criado buracos. Vamos preencher tudo para fazer um quadrado sólido.
            maskArena = imclose(maskArena, strel('disk', 30)); % Fecha buracos grandes (blocos)
            maskArena = imfill(maskArena, 'holes');
            
            %% 7. Obter a BoundingBox
            props = regionprops(maskArena, 'BoundingBox');
            
            if isempty(props)
                warning('Arena não encontrada. A devolver imagem original.');
                croppedImage = image;
                rectCrop = [1 1 size(image,2) size(image,1)];
            else
                rectCrop = props(1).BoundingBox;
                
                % Opcional: Dar uma margem de segurança para garantir que apanhamos a fita preta
                margem = 20; 
                rectCrop(1) = max(1, rectCrop(1) - margem); % X
                rectCrop(2) = max(1, rectCrop(2) - margem); % Y
                rectCrop(3) = rectCrop(3) + margem*2;       % Width
                rectCrop(4) = rectCrop(4) + margem*2;       % Height
                
                croppedImage = imcrop(image, rectCrop);
            end
        end % cropArena

    %% ##############################################################
    %% brief
        % RETORNA: [X_metro, Y_metro] correspondente ao centro do círculo PEQUENO
        function robotXY = getRobotPos(app, image)
        % 1. Processamento de Imagem
            pause(4);
            binIM = app.toBIN(image, 'hsv', app.ratioHSV);
            binIM = app.imageFilter(binIM, {"areaopen", 20; "close", 6});
            props = regionprops(binIM, 'Area', 'Centroid');
            
            imgH = size(image, 1);
            robotXY = []; % Inicializar vazio

            minArea = 100;
            maxArea = 10000;

            validIdx = [props.Area] > minArea & [props.Area] < maxArea;
            validProps = props(validIdx);
            
            % Mostrar a imagem original no eixo do App Designer
            imshow(image, "Parent", app.RobotPos);
            hold (app.RobotPos, 'on');
            
            if length(validProps) >= 2
                % --- LÓGICA DE CÁLCULO (Do código de cima) ---
                
                % Ordenar por Área: 1=Pequeno (Frente), 2=Grande (Trás)
                [~, idx] = sort([validProps.Area], 'ascend');
                smallC = validProps(idx(1)).Centroid;
                largeC = validProps(idx(2)).Centroid;
                
                % Vetor do Grande para o Pequeno
                vec = smallC - largeC; 
                
                % Calcular ângulo (Theta)
                % dy é negativo porque o Y da imagem cresce para baixo
                theta = atan2(-vec(2), vec(1)); 
                
                % Converter posição para metros (Referência: Círculo Pequeno)
                x_m = smallC(1) / (app.ratio * 1000);
                y_m = (imgH - smallC(2)) / (app.ratio * 1000);
                
                robotXY = [x_m, y_m, theta]; 
                
                % --- VISUALIZAÇÃO (O que pediste para adicionar) ---
                
                % 1. Marcar o centro do círculo pequeno (Cruz Verde)
                plot(app.RobotPos, smallC(1), smallC(2), 'g+', 'MarkerSize', 15, 'LineWidth', 2);
                
                % 2. Desenhar linha de orientação (Rosa) entre os marcadores
                plot(app.RobotPos, [largeC(1), smallC(1)], [largeC(2), smallC(2)], 'm-', 'LineWidth', 2);
                
                % 3. Escrever o valor do ângulo e posição na imagem
                infoText = sprintf('X: %.2fm\nY: %.2fm\nAng: %.1f°', x_m, y_m, rad2deg(theta));
                text(app.RobotPos, smallC(1) + 20, smallC(2), infoText, ...
                     'Color', 'g', 'FontSize', 10, 'FontWeight', 'bold', 'BackgroundColor', 'black');
                 
            else
                warning('Não foram detetados os 2 marcadores necessários.');
            end
            
            hold (app.RobotPos, 'off');
        end % getRobotPos

%% ##############################################################
%% brief        
    % Defines the robot movement doing a for loop for the path provided
        function moveRobotSP(app)
            [numRows, ~] = size(app.path);
            posInit = app.path(1,:);
            
            % Inicializa como coluna 3x1 [x; y; theta]
            current_pos = app.getRobotPos( app.cropArena ( app.testSnapshot ) );

            for i = 1:(numRows-1)
                goal_pos = app.path(i+1, :);

                % 2. Calculate Orientation (Theta)

                % 3. Create a 3-Element Pose [x, y, theta]
                startPose = [current_pos(1), current_pos(2)];
                
                % 4. Create Path Matrix (2x2)
                thisPath = [startPose; goal_pos];
                
                % 5. Send to Pure Pursuit (now using startPose with 3 elements)
                app.PurePursuit(thisPath, current_pos, app.cropArena(app.testSnapshot));
                current_pos = app.getRobotPos( app.cropArena ( app.testSnapshot ) );

                % thisPath = [current_pos; goal_pos];
                % app.PurePursuit(thisPath, current_pos, app.testSnapshot);
                pause(1);
            end
        end
        
%% ##############################################################
%% brief  
            function moveRobotMP(app)
                fprintf('\n--- INÍCIO DEBUG MOVE MP ---\n');
                % 1. Verificar Variáveis Críticas
                if isempty(app.path)
                    uialert(app.UIFigure, 'Erro: O caminho (app.path) está vazio. Gere os Waypoints primeiro!', 'Aviso');
                    return;
                end
                
                if isempty(app.map)
                    uialert(app.UIFigure, 'Erro: O mapa (app.map) está vazio. Crie o mapa primeiro!', 'Aviso');
                    return;
                end
            
                [numRows, ~] = size(app.path);
                
                % posInit = app.path(1,:);
            
                % 2. Configurar PRM
                fprintf('A criar PRM...\n');
                prm = mobileRobotPRM(app.map);
                
                % Nota: Se mudaste para NumericEditField na UI, não precisas de str2double.
                % Se mantiveste texto, usa str2double(). Vou assumir que já é Numérico:
                prm.NumNodes = app.NumNodesEditField.Value; 
                prm.ConnectionDistance = app.connDistEditField.Value;
                                 
                % Update mapa (gera os nós aleatórios)
                update(prm);
            
                % Inicializa como coluna 3x1 [x; y; theta]
                current_pos = app.getRobotPos(  app.cropArena( app.getSnapshot( ) ) );
                % pause (1);
                % 3. Ciclo de Movimento
                if numRows < 2
                    app.appNotify("warning", "Number of Rows", "Number of rows is under 2");
                end
            
                for i = 1:(numRows-1)
                    startPose = [current_pos(1), current_pos(2)];
                    
                    % Aceder aos candidatos
                    % (Certifica-te que app.basePossiblities foi preenchido no botão GenMultipleWP)
                    if isempty(app.basePossiblities)
                        app.appNotify ("error", "Bases", 'Base Possibilities vazio!');
                        return;
                    end
                    
                    idx_start = (i-1)*4 + 1;
                    idx_end   = i*4;
                    candidates = app.basePossiblities(idx_start:idx_end, :);
                    
                    % Escolher o candidato mais próximo
                    distancias = zeros(1, 4);
                    for k = 1:4
                        distancias(k) = norm(startPose - candidates(k, :));
                    end
                    [minDist, idxBest] = min(distancias);
                    destination = candidates(idxBest, :);
                    
                    fprintf('Destino escolhido: [%.2f, %.2f] (Dist: %.2f)\n', destination(1), destination(2), minDist);
                    
                    % Calcular Caminho PRM
                    fprintf('A calcular findpath...\n');
                    thisPath = findpath(prm, startPose, destination);
                    
                    if isempty(thisPath)
                        fprintf('ERRO: PRM não encontrou caminho entre Start e Destino!\n');
                        fprintf('Tente aumentar a ConnectionDistance ou o NumNodes.\n');
                        continue; % Salta para o próximo ou usa break para parar
                    else
                        fprintf('Caminho encontrado com %d pontos. A iniciar movimento...\n', size(thisPath,1));
                    end
                    % pause(1);


                    if app.MethodDropDown.Value == "PurePursuit"
                        % Pure Pursuit recebe o caminho INTEIRO e gere a curvatura
                        app.PurePursuit(thisPath, current_pos, app.cropArena(app.getSnapshot()));
                    
                    elseif app.MethodDropDown.Value == "Drive2Point"
                        % Drive2Point tem de ir PONTO a PONTO da lista do PRM
                        % Começamos em k=2 porque k=1 é a posição atual
                        for j = 2:size(thisPath, 1)
                            goalNode = thisPath(j, :);
                            % Chama a função Drive2Point para este nó específico
                            app.Drive2Point(goalNode, current_pos, app.cropArena(app.getSnapshot( ) ) );
                            current_pos = app.getRobotPos(  app.cropArena ( app.getSnapshot( ) ) );
                        end
                    end


                    % Pure Pursuit 1 (Até à base)
                    % app.PurePursuit(thisPath, current_pos, app.cropArena( app.getSnapshot() ) );
                    current_pos = app.getRobotPos( app.cropArena ( app.getSnapshot() ) );

                    % Robot faces Centroid
                    app.rot2Centroid(current_pos, app.path(i+1, :));
                    current_pos = app.getRobotPos( app.cropArena ( app.getSnapshot() ) );

                    if (i == (numRows -1)) % reached Goal
                        return;
                    end
                    

                    basePose = [current_pos(1), current_pos(2)];
                    % fprintf('Chegou à base. À espera de input do utilizador...\n');
                    % 
                    % msg = sprintf("Chegou ao destino intermédio %d. Direção?", i);
                    % selection = uiconfirm(app.UIFigure, msg, "Decisão", ...
                    %     'Options', {'Left', 'Right'}, ...
                    %     'DefaultOption', 1, 'Icon', 'question');
                    % 
                    % fprintf('Utilizador escolheu: %s\n', selection);

                    selection = app.analyzeTrafficSign();
                    
                    % Função de Decisão
                    idx_local = app.moveDecisionIdx(idxBest, selection);
                    intermediateWP = candidates(idx_local, :);
                    
                    % Caminho Final
                    intermediatePath = findpath(prm, basePose, intermediateWP);
                    
                    if isempty(intermediatePath)
                        fprintf('ERRO: Não há caminho para o WP final (offset).\n');
                    else
                        pause(1);
                        fprintf('A iniciar Pure Pursuit 2 (Manobra final)...\n');
                        
                        
                        if app.MethodDropDown.Value == "PurePursuit"
                            % Pure Pursuit recebe o caminho INTEIRO e gere a curvatura
                            app.PurePursuit(thisPath, current_pos, app.cropArena(app.getSnapshot()));
                        
                        elseif app.MethodDropDown.Value == "Drive2Point"
                            % Drive2Point tem de ir PONTO a PONTO da lista do PRM
                            % Começamos em k=2 porque k=1 é a posição atual
                            for l = 2:size(intermediatePath, 1)
                                intermediateGoalNode = intermediatePath(l, :);
                                % Chama a função Drive2Point para este nó específico
                                app.Drive2Point(intermediateGoalNode, current_pos, app.cropArena(app.getSnapshot()));
                                current_pos = app.getRobotPos( app.cropArena ( app.getSnapshot() ) );
                            end
                        end
                        % app.PurePursuit(intermediatePath, current_pos, app.cropArena( app.getSnapshot( ) ));
                    end
                    
                    current_pos = app.getRobotPos( app.cropArena ( app.getSnapshot( ) ) );
                    pause(1);
                end
                fprintf('\n--- FIM MOVE MP ---\n');
            end
    %     function moveRobotMP(app)
    %         [numRows, ~] = size(app.path);
    %         posInit = app.path(1,:);
    % 
    %         prm = mobileRobotPRM(app.map);
    %         prm.NumNodes = app.NumNodesEditField.Value;
    %         prm.ConnectionDistance = app.connDistEditField.Value;
    % 
    %         % Inicializa como coluna 3x1 [x; y; theta]
    %         current_pos = [posInit(1); posInit(2); 0];
    % 
    %         for i = 1:(numRows-1)
    %             startPose = [current_pos(1), current_pos(2)];
    % 
    %             % calculate shortest path from currentPos to WP
    %             candidates = app.basePossiblities(i);
    %             distancias = zeros(1, 4);
    %             for k = 1:4
    %                 distancias(k) = norm(startPose - candidates(k, :));
    %             end
    %             [minDist, idxBest] = min(distancias);
    %             destination = candidates(idxBest, :); % WP chosen as goal
    % 
    %             % Define initial Path from start point to shortest WP from
    %             thisPath = findpath(prm, startPose, destination);
    % 
    %             % currentPos = PurePursuit using PRM
    %             current_pos = app.PurePursuit(thisPath, current_pos, app.cropArena(app.testSnapshot));
    % 
    %             % Detect Sign
    % 

    % 
    % % debug: request user to type "Left" or "Right" for decision
    % % debug: read user input
    %             basePose = [current_pos(1), current_pos(2)];
    % 
    %             % Choose WPi
    %             msg = sprintf("Chegou ao destino");
    %             titlePopup = "Decisao de direçao";
    %             selection = uiconfirm(app.UIFigure, msg, titlePopup, ...
    %                 'Options', {'left', 'right'}, ...
    %                 'DefaultOption', 3, 'CancelOption', 3, 'Icon', 'question');
    % 
    %             intermediateWP = candidates( app.moveDecision (current_pos, candidates, selection) );
    % 
    %             % Define initial Path from start point to shortest WP from
    %             intermediatePath = findpath(prm, basePose, intermediateWP);
    % 
    %             % currentPos = PurePursuit using PRM
    %             current_pos = app.PurePursuit(intermediatePath, current_pos, app.cropArena(app.testSnapshot));
    %         end
    %     end % moveRobotMP


%% ##############################################################
%% brief
        function processedImage = imageFilter(app, binIM, filterSequence)
            processedImage = binIM;
            
            % Loop through the rows of the cell array
            % filterSequence is now expected to be Nx2: {'method', value; 'method', value}
            for i = 1:size(filterSequence, 1)
                operationType = filterSequence{i, 1}; % Get text (e.g., 'erode')
                operationVal  = filterSequence{i, 2}; % Get number (e.g., 4)
                
            se = strel('disk', operationVal);
                
                switch operationType
                    case "areaopen"
                        processedImage = bwareaopen(processedImage, operationVal);
                    case "erode"
                        processedImage = imerode(processedImage, se);     
                    case "dilate"
                        processedImage = imdilate(processedImage, se);
                    case "open"
                        processedImage = imopen(processedImage, se);
                    case "close"
                        processedImage = imclose(processedImage, se);
                end % switch
            end % for
        end % imageFilter

%% ###################################################################
%% brief
        function saveCalibration(app)     
            %% 2. Preparar os dados para escrita
            % Criamos uma "Cell Array" onde a coluna 1 é o Nome e a coluna 2 é o Valor
            dados_para_gravar = {
                'testHSV',   app.testHSV;
                'redHSV',    app.redHSV;
                'yellowHSV', app.yellowHSV;
                'blueHSV',   app.blueHSV;
                'blackHSV',  app.blackHSV;
                'whiteHSV',  app.whiteHSV;
                'ratioHSV',  app.ratioHSV;
                'mapHSV',    app.mapHSV;
                'rBaseHSV',  app.rBaseHSV;
                'gBaseHSV',  app.gBaseHSV;
                'bBaseHSV',  app.bBaseHSV;
                'yBaseHSV',  app.yBaseHSV
            };
        
            %% 3. Gravar no Ficheiro CSV
            nome_ficheiro = app.FileNameEditField.Value + ".csv";
            
            % 'w' = write (cria novo ficheiro ou substitui o existente)
            fid = fopen(nome_ficheiro, 'w'); 
            
            if fid == -1
                error('Não foi possível criar o ficheiro CSV.');
            end
        
            % Escreve o cabeçalho (Opcional)
            fprintf(fid, 'Variavel,Hue,Saturation,Value\n');
        
            % Loop por cada variável
            [num_vars, ~] = size(dados_para_gravar);
            
            for i = 1:num_vars
                nome = dados_para_gravar{i, 1};
                valores = dados_para_gravar{i, 2};
                
                % Escreve o nome da variável
                fprintf(fid, '%s', nome);
                
                % Escreve os valores (adapta-se a arrays de 3 ou 4 elementos)
                for j = 1:length(valores)
                    fprintf(fid, ',%.2f', valores(j));
                end
                
                % Nova linha
                fprintf(fid, '\n');
            end
        
            fclose(fid);
            fprintf('Ficheiro "%s" gravado com sucesso.\n', nome_ficheiro);
        end % saveCalibration
        
        function loadCalibration(app)
            fprintf("Before Load\n");
            app.printCurrentCalibration();
            nome_ficheiro = app.FileNameEditField.Value + ".csv";
            if ~exist(nome_ficheiro, 'file')
                uialert(app.UIFigure, sprintf('O ficheiro %s não existe.', nome_ficheiro), 'Erro');
                return;
            end
        
            % 1. Ler o ficheiro CSV
            opts = detectImportOptions(nome_ficheiro);
            opts.VariableTypes{1} = 'char'; 
            opts.PreserveVariableNames = true;
            
            try
                T = readtable(nome_ficheiro, opts);
            catch
                uialert(app.UIFigure, 'Erro ao ler o ficheiro CSV.', 'Erro');
                return;
            end
        
            % 2. Percorrer cada linha e atualizar as PROPRIEDADES DA APP
            count = 0;
            for i = 1:height(T)
                % Nome da variável (Coluna 1)
                varName = T{i, 1}{1}; 
                
                % Valores numéricos (Colunas 2 até ao fim)
                rawValues = table2array(T(i, 2:end));
                cleanValues = rawValues(~isnan(rawValues));
                
                % --- A CORREÇÃO CRÍTICA ESTÁ AQUI ---
                % Verifica se esta variável existe nas propriedades da App
                if isprop(app, varName)
                    app.(varName) = cleanValues; % Atualiza a propriedade da App
                    count = count + 1;
                else
                    fprintf('Aviso: Variável "%s" encontrada no CSV mas não existe na App.\n', varName);
                end
            end
            
            fprintf('%d variáveis carregadas com sucesso!\n', count);
            
            % (Opcional) Atualizar os Sliders da GUI para refletir a cor selecionada atualmente
            % Isso força a GUI a mostrar os valores novos que acabaste de carregar
            % app.updateSlidersForSelectedColor(); % (Terias de criar esta função auxiliar)
            fprintf("\nAfter Load\n");
            app.printCurrentCalibration();
        end % loadCalibration
        
        function printCurrentCalibration(app)
            % Limpa o ecrã (opcional, remove se quiseres manter o histórico)
            % clc; 
            
            fprintf('\n======================================================\n');
            fprintf('          ESTADO ATUAL DAS VARIÁVEIS (DEBUG)          \n');
            fprintf('======================================================\n');
        
            %% 1. Sign Calibration Variables
            fprintf('\n--- SIGN CALIBRATION (Cores dos Sinais) ---\n');
            fprintf('%-12s : [%.3f, %.3f, %.3f]\n', 'testHSV', app.testHSV);
            fprintf('%-12s : [%.3f, %.3f, %.3f]\n', 'redHSV', app.redHSV);
            fprintf('%-12s : [%.3f, %.3f, %.3f]\n', 'yellowHSV', app.yellowHSV);
            fprintf('%-12s : [%.3f, %.3f, %.3f]\n', 'blueHSV', app.blueHSV);
            fprintf('%-12s : [%.3f, %.3f, %.3f]\n', 'blackHSV', app.blackHSV);
            fprintf('%-12s : [%.3f, %.3f, %.3f]\n', 'whiteHSV', app.whiteHSV);
            
            % Imprimir a lista de cores (Strings)
            fprintf('%-12s : %s\n', 'colorList', strjoin(app.colorList, ', '));
        
            %% 2. MAP Calibration Variables
            fprintf('\n--- MAP CALIBRATION (Mapeamento e Bases) ---\n');
            fprintf('%-12s : %.6f (mm/pixel approx)\n', 'ratio', app.ratio);
            fprintf('%-12s : [%.3f, %.3f, %.3f]\n', 'ratioHSV', app.ratioHSV);
            fprintf('%-12s : [%.3f, %.3f, %.3f]\n', 'mapHSV', app.mapHSV);
            
            fprintf('\n--- Bases das Cores (HSV) ---\n');
            fprintf('%-12s : [%.3f, %.3f, %.3f]\n', 'rBaseHSV', app.rBaseHSV);
            fprintf('%-12s : [%.3f, %.3f, %.3f]\n', 'gBaseHSV', app.gBaseHSV);
            fprintf('%-12s : [%.3f, %.3f, %.3f]\n', 'bBaseHSV', app.bBaseHSV);
            fprintf('%-12s : [%.3f, %.3f, %.3f]\n', 'yBaseHSV', app.yBaseHSV);
        
            %% 3. Objetos complexos (Verificação de estado)
            fprintf('\n--- ESTADO DOS OBJETOS ---\n');
            
            if isempty(app.map)
                statusMap = 'Vazio / Não Inicializado';
            else
                statusMap = sprintf('Criado (Classe: %s)', class(app.map));
            end
            fprintf('%-12s : %s\n', 'map', statusMap);
            
            if isempty(app.testSnapshot)
                statusSnap = 'Vazio';
            else
                dim = size(app.testSnapshot);
                statusSnap = sprintf('Imagem Carregada [%d x %d x %d]', dim(1), dim(2), dim(3));
            end
            fprintf('%-12s : %s\n', 'testSnapshot', statusSnap);
            
            fprintf('======================================================\n\n');
        end
        
%% #######################################################################
%% brief
        function appNotify(app, notificationType, notificationTitle, notificationMessage)
            
            switch notificationType
                case "success"
                    uiconfirm(app.UIFigure, notificationMessage, notificationTitle, 'Icon', 'success', 'Options', {'OK'});
        
                case "warning"
                    uialert(app.UIFigure, notificationMessage, notificationTitle, 'Icon', 'warning'); 
        
                case "alert"
                    uialert(app.UIFigure, notificationMessage, notificationTitle, 'Icon', 'error');
        
                case "info"
                    uialert(app.UIFigure, notificationMessage, notificationTitle, 'Icon', 'info');
        
                case "question"
                    uiconfirm(app.UIFigure, notificationMessage, notificationTitle, 'Icon', 'question');
            end % switch
        end % notify
        
        function refreshBaseColorMap(app)
            app.valueSet = {app.rBaseHSV, app.gBaseHSV, app.bBaseHSV, app.yBaseHSV};
            app.baseColorMap = containers.Map(app.baseColors, app.valueSet);
            
            app.appNotify("info", "Map of Values", "Updated Map of HSV Values");
        end
        

%% ################################################################
%% brief
        function newCurrPos = rot2Centroid(app, currentPos, currentCentroid)
            % 1. Calcular o ângulo desejado
            dx = currentCentroid(1) - currentPos(1);
            dy = currentCentroid(2) - currentPos(2);
            targetTheta = atan2(dy, dx);
            
            % 2. Calcular o erro (Diferença entre onde olha e onde quer olhar)
            erroTheta = targetTheta - currentPos(3);
            
            % Normalizar o erro para [-pi, pi] (ex: virar 270º esq é o mesmo que -90º dir)
            while erroTheta > pi,  erroTheta = erroTheta - 2*pi; end
            while erroTheta < -pi, erroTheta = erroTheta + 2*pi; end
            
            fprintf('A Rodar... Atual: %.2f rad | Alvo: %.2f rad | Erro: %.2f rad\n', ...
                    currentPos(3), targetTheta, erroTheta);
                
            % 3. Comandar os Motores para Rodar (Spin Turn)
            % W = (Vr - Vl) / L. Se Vl = -Vr, então W = 2*Vr/L => Vr = W*L/2
            % Vamos rodar devagar para ser preciso
            angVelocity = 1.0; % rad/s (ajusta se for muito rápido)
            wheelSpeed = (angVelocity * app.WheelBaseEditField.Value) / 2;
            
            % Converter m/s para Power (0-100) - Aproximação: 100 power ~= 0.5 m/s
            power = (wheelSpeed / 0.5) * 100;
            power = max(min(abs(power), 30), 10); % Limita entre 10% e 30% para não ser brusco
            
            if erroTheta > 0
                % Rodar para a Esquerda (Anti-horário): Dir Frente, Esq Trás
                app.motorEsq.Speed = -power;
                app.motorDir.Speed =  power;
            else
                % Rodar para a Direita (Horário): Dir Trás, Esq Frente
                app.motorEsq.Speed =  power;
                app.motorDir.Speed = -power;
            end
            
            start(app.motorEsq);
            start(app.motorDir);
            
            % 4. Esperar o tempo necessário para rodar
            % Tempo = Angulo / VelocidadeAngular
            tempoRotacao = abs(erroTheta) / angVelocity;
            pause(tempoRotacao);
            
            stop(app.motorEsq);
            stop(app.motorDir);
            
            % 5. Atualizar a Pose Final
            newCurrPos = currentPos;
            newCurrPos(3) = targetTheta; % Assumimos que rodou perfeito
            fprintf('Rotação concluída. Nova orientação: %.2f rad\n', newCurrPos(3));
        end % rot2Centroid
        
%% ####################################################### LEGACY
%% Based on the angle given by the robot theta
        function idx = moveDecision(app, currentPos, possibilities, signDir)
           % signDir deve ser 'Left' ou 'Right' (input do uiconfirm)
            theta = currentPos(3); 
            
            % Normalizar theta para 0 a 2*pi para facilitar comparações, se necessário
            theta = mod(theta, 2*pi);
        
            idx = 1; % Valor por defeito para evitar crash
        
            % 1. Determinar orientação aproximada (Norte, Sul, Este, Oeste)
            % Usa lógica IF em vez de switch para intervalos
            
            % -- CIMA (pi/2) --
            if (theta >= pi/4 && theta < 3*pi/4) 
                % O Robô está virado para CIMA (Y diminui na imagem, mas Y aumenta no referencial? Depende do teu referencial)
                % Assumindo referencial normal (Y aumenta para cima):
                % Left = X diminui, Right = X aumenta
                for i = 1 : size(possibilities, 1)
                    curPossibility = possibilities(i,:);
                    if strcmpi(signDir, 'Left')
                        if curPossibility(1) < currentPos(1), idx = i; end % Mais à esquerda
                    elseif strcmpi(signDir, 'Right')
                        if curPossibility(1) > currentPos(1), idx = i; end % Mais à direita
                    end
                end
        
            % -- ESQUERDA (pi ou -pi) --
            elseif (theta >= 3*pi/4 && theta < 5*pi/4)
                % Virado para a Esquerda
                % Left = Y diminui, Right = Y aumenta
                for i = 1 : size(possibilities, 1)
                    curPossibility = possibilities(i,:);
                    if strcmpi(signDir, 'Left')
                        if curPossibility(2) > currentPos(2), idx = i; end
                    elseif strcmpi(signDir, 'Right')
                        if curPossibility(2) < currentPos(2), idx = i; end
                    end
                end
                
            % -- BAIXO (3*pi/2 ou -pi/2) --
            elseif (theta >= 5*pi/4 && theta < 7*pi/4)
                % Virado para Baixo
                % Left = X aumenta, Right = X diminui
                for i = 1 : size(possibilities, 1)
                    curPossibility = possibilities(i,:);
                    if strcmpi(signDir, 'Left')
                        if curPossibility(1) > currentPos(1), idx = i; end
                    elseif strcmpi(signDir, 'Right')
                        if curPossibility(1) < currentPos(1), idx = i; end
                    end
                end
                
            % -- DIREITA (0 ou 2*pi) --
            else 
                % Virado para a Direita
                % Left = Y aumenta, Right = Y diminui
                for i = 1 : size(possibilities, 1)
                    curPossibility = possibilities(i,:);
                    if strcmpi(signDir, 'Left')
                        if curPossibility(2) < currentPos(2), idx = i; end
                    elseif strcmpi(signDir, 'Right')
                        if curPossibility(2) > currentPos(2), idx = i; end
                    end
                end
            end
        end % moveDecision 

%% #####################################################################
%% brief 
% Decides based on the selected WP idx to go to the Left or Right 
% of the obstacle 
function idxDestino = moveDecisionIdx(app, currentIdx, signDir)
            % idxMelhor é onde o robô está AGORA (1=EsqMap, 2=DirMap, 3=Cima, 4=Baixo)
            if strcmpi(signDir, 'left') % --- O UTILIZADOR QUER ESQUERDA ---
                switch currentIdx
                    case 1 % Estou na Esquerda do Mapa -> Minha Esq é Cima
                        idxDestino = 3; 
                    case 2 % Estou na Direita do Mapa -> Minha Esq é Baixo
                        idxDestino = 4;
                    case 3 % Estou em Cima -> Minha Esq é Direita do Mapa
                        idxDestino = 2;
                    case 4 % Estou em Baixo -> Minha Esq é Esquerda do Mapa
                        idxDestino = 1;
                end
                
            elseif strcmpi(signDir, 'right') % --- O UTILIZADOR QUER DIREITA ---
                switch currentIdx
                    case 1 % Estou na Esquerda do Mapa -> Minha Dir é Baixo
                        idxDestino = 4; 
                    case 2 % Estou na Direita do Mapa -> Minha Dir é Cima
                        idxDestino = 3;
                    case 3 % Estou em Cima -> Minha Dir é Esquerda do Mapa
                        idxDestino = 1;
                    case 4 % Estou em Baixo -> Minha Dir é Direita do Mapa
                        idxDestino = 2;
                end
            else
                fprintf('Opção inválida ou vazia. A saltar passo extra.\n');
            end
end

function signDecision = analyzeTrafficSign(app)
    fprintf('\n>>> INÍCIO: Análise de Sinal (IP CAM - DEBUG VISUAL) <<<\n');
    signDecision = "None";
    image = [];
    
    % --- 1. CONEXÃO ---
    persistent cam 
    if isempty(cam) || ~isvalid(cam)
        try
            cam = ipcam('http://192.168.0.19:8080/video', 'admin', '12345');
        catch ME
            fprintf('ERRO CONEXÃO: %s\n', ME.message); return;
        end
    end
    
    % --- 2. SNAPSHOT ---
    try
        image = snapshot(cam);
        if isempty(image), return; end
        
        % Mostra imagem bruta (Figura 100)
        figure(100); imshow(image); title('IMAGEM BRUTA'); drawnow;
    catch ME
        fprintf('ERRO SNAPSHOT: %s\n', ME.message); clear cam; return;
    end

    % --- 3. PROCESSAMENTO (COM VARIÁVEIS ALINHADAS AO PLOT) ---
    fprintf('DEBUG: A processar...\n');
    
    % A. Detetar Azul
    % app.blueHSV = app.idColor(rgb2hsv(image), 'Blue');
    blueBIN = app.toBIN( image, 'hsv', app.blueHSV );
    maskBlueFiltered = app.imageFilter( blueBIN, {"areaopen", 500; "close", 10} );
    maskBlueFilled = imfill(maskBlueFiltered, 'holes'); 
    
    propsBlue = regionprops(maskBlueFilled, 'Area', 'BoundingBox');
    
    if isempty(propsBlue)
        fprintf('AVISO: Nenhum azul detetado.\n');
        return; 
    end
    
    [maxArea, idx] = max([propsBlue.Area]);
    if maxArea <= 2000
        fprintf('AVISO: Azul muito pequeno (%d px).\n', maxArea);
        return; 
    end
    
    % Se chegou aqui, temos sinal
    signObj = propsBlue(idx);
    bbox = signObj.BoundingBox;
    
    % B. Erosão e Recorte
    se = strel('disk', 15); 
    maskBlueEroded = imerode(maskBlueFilled, se);
    
    imCropRGB = imcrop(image, bbox);
    maskCrop = imcrop(maskBlueEroded, bbox);
    
    % Máscara
    imCropMasked = imCropRGB; 
    for k=1:3
        chan = imCropMasked(:,:,k);
        chan(~maskCrop) = 0; 
        imCropMasked(:,:,k) = chan;
    end

   % ... (O teu código anterior A e B fica igual até à linha imCropMasked) ...

    % =================================================================
    % C. DETEÇÃO SETA (CORRIGIDO PARA IGNORAR A BORDA)
    % =================================================================
    maskGray = rgb2gray(imCropMasked);
    imGrayEnhanced = imadjust(maskGray);
    
    % 1. Binarização
    binRaw = imbinarize(imGrayEnhanced, 'adaptive', 'Sensitivity', 0.50);
    
    % 2. Limpeza básica (manter dentro do azul e tirar ruído pequeno)
    bwWhiteMasked = binRaw & maskCrop; 
    bwWhiteCleaned = bwareaopen(bwWhiteMasked, 50); 
    
    % --- A CORREÇÃO MÁGICA: MÁSCARA CENTRAL ---
    % Criamos um círculo no centro da imagem que tapa as bordas.
    % Só aceitamos objetos que estejam dentro deste círculo central (70% do tamanho).
    [h, w] = size(bwWhiteCleaned);
    [xx, yy] = meshgrid(1:w, 1:h);
    centerX = w/2;
    centerY = h/2;
    radius = min(w, h) * 0.35; % Raio de 35% (Diametro 70%)
    
    % Cria a máscara circular no centro
    centerMask = ((xx - centerX).^2 + (yy - centerY).^2) <= radius^2;
    
    % Aplica a máscara: Apaga tudo o que for branco mas esteja na borda (o aro)
    bwCenterOnly = bwWhiteCleaned & centerMask;
    
    % Agora sim, pedimos o MAIOR objeto. 
    % Como apagámos o aro, o maior objeto restante será a seta.
    % (Usamos bwpropfilt na imagem original 'bwWhiteCleaned' usando os indices do centro,
    % ou simplesmente filtramos o resultado central. Vamos filtrar o central para garantir).
    bwWhiteFinal = bwpropfilt(bwCenterOnly, 'Area', 1);
    
    % Se a máscara central cortou demasiado a seta, podemos recuperar a forma original:
    % (Opcional: recupera a forma completa da seta se ela tocar na máscara central)
    if any(bwWhiteFinal(:))
        bwWhiteFinal = imreconstruct(logical(bwWhiteFinal), logical(bwWhiteCleaned));
    end
    
    % Guardamos para visualização
    app.whiteHSV = binRaw; 

    % =================================================================
    % D. DECISÃO
    % =================================================================
    propsWhite = regionprops(bwWhiteFinal, 'Area', 'Centroid', 'PixelIdxList');
    
    % Inicializar variáveis
    arrowFound = false;
    cx = 0; cy = 0; pxTip = 0; pyTip = 0;
    
    if ~isempty(propsWhite)
        arrowFound = true;
        bestArrow = propsWhite(1); % Agora deve ser mesmo a seta
        
        cx = bestArrow.Centroid(1);
        cy = bestArrow.Centroid(2);
        
        [~, px] = ind2sub(size(bwWhiteFinal), bestArrow.PixelIdxList);
        [py_all, ~] = ind2sub(size(bwWhiteFinal), bestArrow.PixelIdxList);
        
        dist = (px - cx).^2;
        [~, idxFar] = max(dist);
        pxTip = px(idxFar); 
        pyTip = py_all(idxFar);
        
        if pxTip < cx 
            signDecision = "Left";
        else
            signDecision = "Right";
        end
        fprintf('>>> DECISÃO: %s <<<\n', signDecision);
    else
        fprintf('RESULTADO: Sinal Azul detetado. Aro removido, mas não sobrou seta no centro.\n');
    end

    % --- 4. VISUALIZAÇÃO (FIGURE 99) ---
    fprintf('DEBUG: A gerar Figure 99...\n');
    figure(99); clf;
    set(gcf, 'Name', 'Pipeline Debug', 'NumberTitle', 'off');

    % Linha 1: Processo do Azul
    subplot(3,3,1); imshow(image); title('1. Original');
    subplot(3,3,2); imshow(app.blueHSV); title('2. Azul Raw');
    subplot(3,3,3); imshow(maskBlueEroded); title('3. Molde Erodido');

    % Linha 2: Recortes e Contraste
    subplot(3,3,4); imshow(imCropRGB); title('4. Recorte');
    subplot(3,3,5); imshow(imCropMasked); title('5. Mascarado');
    subplot(3,3,6); imshow(imGrayEnhanced); title('6. Contraste');

    % Linha 3: Processo do Branco e Resultado
    subplot(3,3,7); imshow(app.whiteHSV); title('7. Binario Raw');
    subplot(3,3,8); imshow(bwWhiteFinal); title('8. Seta Final');
    if arrowFound, hold on; visboundaries(bwWhiteFinal,'Color','g'); end

    subplot(3,3,9); imshow(imCropRGB); title(['Decisão: ' char(signDecision)]);
    if arrowFound
        hold on;
        plot(cx, cy, 'g+', 'MarkerSize', 15, 'LineWidth', 2);
        plot(pxTip, pyTip, 'r*', 'MarkerSize', 15, 'LineWidth', 2);
        legend('Centro', 'Ponta');
    end
    drawnow;
    fprintf('>>> FIM <<<\n');
end
    end % Methods

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Add Library paths to work with Robot
            addpath(genpath(app.pathToolBox));
            addpath(genpath(app.pathToolBox2));
            addpath(genpath(app.pathToolBox3));
           
            app.baseColors = {'Red', 'Green', 'Blue', 'Yellow'};
            app.valueSet = {app.rBaseHSV, app.gBaseHSV, app.bBaseHSV, app.yBaseHSV};
            app.baseColorMap = containers.Map(app.baseColors, app.valueSet);

            app.initRobot();
        end

        % Value changed function: IPCamConnectButton
        function IPCamConnectButtonValueChanged(app, event)
            value = app.IPCamConnectButton.Value;

            switch value
                case 1
                    try
                        camera_link = "http://" + app.camIPEditField.Value + ":8080/video";   
                        app.ipCam = ipcam(camera_link, app.userEditField.Value, app.passwordEditField.Value);
                        app.appNotify("success", "Camera Connection - IP", "Successfuly connected to IP Cam");
                        app.CameraLamp.Color = 'green';
                    catch
                        app.appNotify("Error", "Camera Connection - IP", "Unable to connect to IP Cam");
                        app.CameraLamp.Color = 'red';
                    end
                case 0
                    clear cam;
                    app.appNotify("success", "Camera Connection - IP", "Successfuly disconnected to IP Cam");
                    app.CameraLamp.Color = 'red';
            end
        end

        % Value changed function: USBCamConnectButton
        function USBCamConnectButtonValueChanged(app, event)
            value = app.USBCamConnectButton.Value;
            switch value
                case 1
                    imaqreset;
                    try
                        app.vid = videoinput('winvideo', app.camIDDropDown.Value);
                    catch ME
                        uialert(app.UIFigure, ME.message, 'Error');
                        app.CameraLamp.Color = 'red';
                        return;
                    end
                
                    app.vid.FramesPerTrigger = 1;
                    app.vid.TriggerRepeat = Inf;
                    app.vid.ReturnedColorSpace = 'RGB';
                    triggerconfig(app.vid, 'manual');
                
                    start(app.vid);
                    app.CameraLamp.Color = 'green';
                    
                case 0
                    % Stop video input
                    if isempty(app.timerObj)
                        if ~isempty(app.vid) && isvalid(app.vid)
                            stop(app.vid);
                            delete(app.vid);
                            app.vid = [];
                            app.CameraLamp.Color = 'red';
                        end
                    else 
                        uialert(app.UIFigure, 'Processing still occuring. Stop it to disconnect.', 'Error');
                        app.IPCamConnectButton.Value = 1;
                    end
            end % switch
        end

        % Value changed function: StartStopProcessingButton
        function StartStopProcessingButtonValueChanged(app, event)
            value = app.StartStopProcessingButton.Value;
            switch value
                case 1
                    if isempty(app.vid) || ~isvalid(app.vid)
                        uialert(app.UIFigure, 'Camera not connected!', 'Error');
                        app.StartStopProcessingButton.Value = 0;
                        return;
                    end
        
                    % Start the camera if not running
                    if strcmp(app.vid.Running, 'off')
                        start(app.vid);
                    end
                
                    % Only create timer if it doesn’t exist yet
                    if isempty(app.timerObj) || ~isvalid(app.timerObj)
                        fps = 20; % frame rate
                        app.timerObj = timer('ExecutionMode','fixedRate', ...
                                             'Period',1/fps, ...
                                             'TimerFcn', @(src,event) app.updateVideoFrame(src, event), ...
                                             'BusyMode','drop');
                        start(app.timerObj);
                    end %StartProcessing

                case 0
                    % Stop timer
                    if ~isempty(app.timerObj) && isvalid(app.timerObj)
                        stop(app.timerObj);
                        delete(app.timerObj);
                        app.timerObj = [];
                    end
            end % switch
        end

        % Button pushed function: ApplyCalibrationButton
        function ApplyCalibrationButtonPushed(app, event)
            switch app.toAdjustApplyDropDown.Value
                case "Sign"
                    switch app.forColorDropDown.Value
                        case 'Red'
                            app.redHSV(1) = app.HSlider.Value;
                            app.redHSV(2) = app.SSlider.Value;
                            app.redHSV(3) = app.VSlider.Value;
                            disp("redHSV : " + app.redHSV);
                        case 'Yellow'
                            app.yellowHSV(1) = app.HSlider.Value;
                            app.yellowHSV(2) = app.SSlider.Value;
                            app.yellowHSV(3) = app.VSlider.Value;
                        case 'Blue'
                            app.blueHSV(1) = app.HSlider.Value;
                            app.blueHSV(2) = app.SSlider.Value;
                            app.blueHSV(3) = app.VSlider.Value;
                        case 'Black'
                            app.blackHSV(1) = app.HSlider.Value;
                            app.blackHSV(2) = app.SSlider.Value;
                            app.blackHSV(3) = app.VSlider.Value;
                        case 'Green'
                            app.greenHSV(1) = app.HSlider.Value;
                            app.greenHSV(2) = app.SSlider.Value;
                            app.greenHSV(3) = app.VSlider.Value;
                    end % switch 

                case "Map"
                    app.mapHSV(1) = app.HSlider.Value;
                    app.mapHSV(2) = app.SSlider.Value;
                    app.mapHSV(3) = app.VSlider.Value;

                case "Ratio"
                    app.ratioHSV(1) = app.HSlider.Value;
                    app.ratioHSV(2) = app.SSlider.Value;
                    app.ratioHSV(3) = app.VSlider.Value;

                case "Base"
                    switch app.forColorDropDown.Value
                        case 'Red'
                            app.rBaseHSV(1) = app.HSlider.Value;
                            app.rBaseHSV(2) = app.SSlider.Value;
                            app.rBaseHSV(3) = app.VSlider.Value;
                        case 'Yellow'
                            app.yBaseHSV(1) = app.HSlider.Value;
                            app.yBaseHSV(2) = app.SSlider.Value;
                            app.yBaseHSV(3) = app.VSlider.Value;
                        case 'Blue'
                            app.bBaseHSV(1) = app.HSlider.Value;
                            app.bBaseHSV(2) = app.SSlider.Value;
                            app.bBaseHSV(3) = app.VSlider.Value;
                        case 'Green'
                            app.gBaseHSV(1) = app.HSlider.Value;
                            app.gBaseHSV(2) = app.SSlider.Value;
                            app.gBaseHSV(3) = app.VSlider.Value;
                    end % Base Color
             end % switch base
             app.refreshBaseColorMap();
        end

        % Button pushed function: GInputCalibrationButton
        function GInputCalibrationButtonPushed(app, event)
            % 1. Verificar se a câmara está ligada
            if isempty(app.vid)
                uialert(app.UIFigure, 'Ligue a câmera primeiro!', 'Erro');
                return;
            end
        
            % 2. PAUSAR O VÍDEO (Para a imagem não mexer)
            if ~isempty(app.timerObj) && isvalid(app.timerObj)
                stop(app.timerObj);
                delete(app.timerObj);
                app.timerObj = [];
            end
            
            % Usamos try-catch para garantir que o vídeo volta mesmo se houver erro
            try
                % Captura o frame estático atual para clicar nele
                frame = getsnapshot(app.vid);
                frame = imgaussfilt(frame, 1);
                % IM_double = double(frame);

                % grIM = rgb2gray(frame);                                % Grayscale the image
                bwIM = frame;
                % bwIM = filterBinImg(app, grIM, "Shape");
    
                % croppedRGB = zeros(size(IM_double));
                % croppedRGB(:,:,1) = IM_double(:,:,1).*bwIM;
                % croppedRGB(:,:,2) = IM_double(:,:,2).*bwIM;
                % croppedRGB(:,:,3) = IM_double(:,:,3).*bwIM;
                
                croppedRGB = bwIM;
                cropHSV = rgb2hsv(uint8(croppedRGB));

                % Mostra a imagem estática
                imshow(cropHSV, 'Parent', app.PreviewCam);
                
                % --- SEQUÊNCIA DE CLIQUES ---
                % Usa a função pickColor que criámos antes com drawpoint
                if app.forColorDropDown.Value == "Red"
                    app.redHSV = pickColor(app, cropHSV, 'Red');
                elseif app.forColorDropDown.Value == "Blue"    
                    app.blueHSV= pickColor(app, cropHSV, 'Blue');
                elseif app.forColorDropDown.Value == "Yellow"
                    app.yellowHSV = pickColor(app, cropHSV, 'Yellow');
                elseif app.forColorDropDown.Value == "Black"
                    app.blackHSV = pickColor(app, cropHSV, 'Black');
                elseif app.forColorDropDown.Value == "White"
                    app.blackHSV = pickColor(app, cropHSV, 'White');
                end
                
                % Mensagem de Sucesso
                title(app.PreviewCam, 'Calibração Concluída!', 'Color', 'green', 'FontSize', 14);
                pause(1); % Pequena pausa para veres a mensagem
                
            catch ME
                % Se algo correr mal (ex: erro no código), avisa e não bloqueia
                uialert(app.UIFigure, ME.message, 'Erro na Calibração');
                title(app.PreviewCam, 'Erro na Calibração', 'Color', 'red');
            end
            
            % 3. REINICIAR O VÍDEO (A PARTE MAIS IMPORTANTE)
            if isempty(app.vid) || ~isvalid(app.vid)
                uialert(app.UIFigure, 'Camera not connected!', 'Error');
                return;
            end
        
            % Start the camera if not running
            if strcmp(app.vid.Running, 'off')
                start(app.vid);
            end
        
            % Only create timer if it doesn’t exist yet
            if isempty(app.timerObj) || ~isvalid(app.timerObj)
                fps = 20; % frame rate
                app.timerObj = timer('ExecutionMode','fixedRate', ...
                                     'Period',1/fps, ...
                                     'TimerFcn', @(src,event) app.updateVideoFrame(src, event), ...
                                     'BusyMode','drop');
                start(app.timerObj);
            end %calibrate
        end

        % Value changed function: HSlider
        function HSliderValueChanged(app, event)
            app.testHSV(1) = app.HSlider.Value;
        end

        % Value changed function: SSlider
        function SSliderValueChanged(app, event)
            app.testHSV(2) = app.SSlider.Value;
        end

        % Value changed function: VSlider
        function VSliderValueChanged(app, event)
            app.testHSV(3) = app.VSlider.Value;
        end

        % Button pushed function: GetSnapshotButton
        function GetSnapshotButtonPushed(app, event)
            app.testSnapshot = app.cropArena( app.getSnapshot());
            imshow(app.testSnapshot, "Parent", app.Preview);
            imshow(app.toBIN(app.testSnapshot, 'hsv', [app.HSlider.Value, ...
                                                        app.SSlider.Value ...
                                                        app.VSlider.Value]), ...
                                                        "Parent", app.HSVMask);
            imshow(rgb2hsv(app.testSnapshot), "Parent", app.HSVView);
        end

        % Button pushed function: MapCreationButton
        function MapCreationButtonPushed(app, event)
            image = app.getSnapshot();
            image = app.cropArena(image);
            binIM = app.toBIN(image, 'hsv_map', app.mapHSV);
            imshow(binIM, "Parent", app.MapView);
            app.map = app.createMapVar(logical(binIM));
        end

        % Value changed function: ConnectRobotButton
        function ConnectRobotButtonValueChanged(app, event)
            value = app.ConnectRobotButton.Value;
            switch value
                case 1 % CONNECT
                    try
                        app.initRobot();
                        app.appNotify("success", "Robot Connect", "Robot Connected successfully");
                    catch ME
                        % It is helpful to print the error to command window for debugging
                        disp(ME.message);
                        app.appNotify("warning", "Robot Connect", "Error connecting. Check USB and Power.");
                        app.ConnectRobotButton.Value = 0;
                    end
                    
                case 0 % DISCONNECT
                    try
                        % FIX: Check if the Hardware Object exists, not the function 'initRobot'
                        if ~isempty(app.myLego)
                            
                            % 1. Stop motors first so it doesn't run away
                            try
                                stop(app.motorEsq);
                                stop(app.motorDir);
                            catch
                                % Ignore errors here in case motors were already lost
                            end
                            
                            % 2. CLEAR VARIABLES TO DISCONNECT
                            % Setting these to empty [] forces MATLAB to release the USB resource
                            app.motorEsq = [];
                            app.motorDir = [];
                            app.myLego = []; 
                            
                            % Optional: Clear the kinematics model too
                            app.robotKinematics = [];
                        end
                        
                        app.appNotify("success", "Robot Disconnect", "Robot disconnected successfully");
                        
                    catch ME
                        disp(ME.message);
                        app.appNotify("warning", "Robot Disconnect", "Error disconnecting.");
                    end
            end % switch
        end

        % Button pushed function: GetRatioButton
        function GetRatioButtonPushed(app, event)
            image = app.getSnapshot();
            image = app.cropArena(image);
            binIM = app.toBIN(image, 'hsv', app.ratioHSV);
            binIM = app.imageFilter(binIM, {"dilate", 2; "erode", 2; "areaopen",10});
            imshow(binIM, "Parent", app.Preview);
            app.ratio = app.getRatio(binIM);
            
            msg = "Ratio Value: " + num2str(app.ratio, '%.2f');
            app.appNotify('info', 'Ratio Report', msg);
        end

        % Button pushed function: GenSingleWPButton
        function GenSingleWPButtonPushed(app, event)
            image = app.getSnapshot();
            image = app.cropArena(image);

            % 1. Get the full position vector [x, y, theta]
            fullPose = app.getRobotPos(image); 
            
            % 2. Extract only the first two values [x, y]
            posInit = fullPose(1:2);
            pos1BIN = app.toBIN( image, 'hsv', app.baseColorMap( app.WP_1.Value ) );
            [pos1Px, pos1] = app.getWaypoint( app.imageFilter( pos1BIN, {"dilate", 2; "erode", 2; "areaopen",10} ));
            pos2BIN = app.toBIN( image, 'hsv', app.baseColorMap( app.WP_2.Value ) );
            [pos2Px, pos2] = app.getWaypoint( app.imageFilter( pos2BIN, {"dilate", 2; "erode", 2; "areaopen",10} ));
            pos3BIN = app.toBIN( image, 'hsv', app.baseColorMap( app.WP_3.Value ) );
            [pos3Px, pos3] = app.getWaypoint( app.imageFilter( pos3BIN, {"dilate", 2; "erode", 2; "areaopen",10} ));
            pos4BIN = app.toBIN( image, 'hsv', app.baseColorMap( app.WP_4.Value ) );
            [pos4Px, pos4] = app.getWaypoint( app.imageFilter( pos4BIN, {"dilate", 2; "erode", 2; "areaopen",10} ));

            app.path = [posInit, pos1, pos2, pos3, pos4];

            imshow(image, "Parent", app.Pos1);
            hold (app.Pos1, 'on');
            plot(app.Pos1, pos1Px(1), pos1Px(2), 'r+', 'MarkerSize', 15, 'LineWidth', 2);
            hold (app.Pos1, 'off');

            imshow(image, "Parent", app.Pos2);
            hold (app.Pos2, 'on');
            plot(app.Pos2, pos2Px(1), pos2Px(2), 'r+', 'MarkerSize', 15, 'LineWidth', 2);
            hold (app.Pos2, 'off');

            imshow(image, "Parent", app.Pos3);
            hold (app.Pos3, 'on');
            plot(app.Pos3, pos3Px(1), pos3Px(2), 'r+', 'MarkerSize', 15, 'LineWidth', 2);
            hold (app.Pos3, 'off');

            imshow(image, "Parent", app.Pos4);
            hold (app.Pos4, 'on');
            plot(app.Pos4, pos4Px(1), pos4Px(2), 'r+', 'MarkerSize', 15, 'LineWidth', 2);
            hold (app.Pos4, 'off');
            % Select the tab so the user sees the result immediately
            app.TabGroup.SelectedTab = app.MapTab;
        
            % 3. Construct Path Matrix (CRITICAL FIX: Use semicolons ;)
            % This creates a 5x2 Matrix (Rows = Points, Cols = X,Y)
            app.path = [posInit; pos1; pos2; pos3; pos4];
        
            % 4. Visualization in PathView
            app.TabGroup.SelectedTab = app.PathTab; % Switch to the Path Tab
            ax = app.PathView;                      % Create short variable for cleaner code
            cla(ax);                                % Clear previous plots
            hold(ax, 'on');
        
            % A. Plot the Trajectory Line (Blue Dashed)
            plot(ax, app.path(:,1), app.path(:,2), 'b--o', 'LineWidth', 1);
        
            % B. Plot Start Position (Green Star - Row 1)
            plot(ax, app.path(1,1), app.path(1,2), 'g*', 'MarkerSize', 15, 'LineWidth', 2);
            text(ax, app.path(1,1), app.path(1,2)+0.02, 'Start', 'Color', 'g', 'FontWeight', 'bold');
        
            % C. Plot Waypoints (Red Crosses - Rows 2 to End)
            % We use 2:end to select all waypoints at once
            plot(ax, app.path(2:end, 1), app.path(2:end, 2), 'r+', 'MarkerSize', 15, 'LineWidth', 2);
            
            % D. Label the Waypoints
            for i = 2:size(app.path, 1)
                text(ax, app.path(i,1), app.path(i,2)+0.02, sprintf('WP%d', i-1), 'Color', 'r', 'FontWeight', 'bold');
            end
        
            % 5. Format Axes
            title(ax, 'Generated Path');
            xlabel(ax, 'X [m]'); ylabel(ax, 'Y [m]');
            grid(ax, 'on');
            axis(ax, 'equal');
            hold(ax, 'off');
            fprintf('Path generated with %d points.\n', height(app.path));
        end

        % Button pushed function: GenMultipleWPButton
        function GenMultipleWPButtonPushed(app, event)
            image = app.getSnapshot();
            image = app.cropArena(image);
    
            % 1. Get the full position vector [x, y, theta]
            fullPose = app.getRobotPos(image); 
            
            % 2. Extract only the first two values [x, y]
            posInit = fullPose(1:2);
            pos1BIN = app.toBIN( image, 'hsv', app.baseColorMap( app.WP_1.Value ) );
            [pos1Px, pos1] = app.getWaypoint( app.imageFilter( pos1BIN, {"dilate", 20; "erode", 2; "areaopen",10} ));
            pos2BIN = app.toBIN( image, 'hsv', app.baseColorMap( app.WP_2.Value ) );
            [pos2Px, pos2] = app.getWaypoint( app.imageFilter( pos2BIN, {"dilate", 20; "erode", 2; "areaopen",10} ));
            pos3BIN = app.toBIN( image, 'hsv', app.baseColorMap( app.WP_3.Value ) );
            [pos3Px, pos3] = app.getWaypoint( app.imageFilter( pos3BIN, {"dilate", 20; "erode", 2; "areaopen",10} ));
            pos4BIN = app.toBIN( image, 'hsv', app.baseColorMap( app.WP_4.Value ) );
            [pos4Px, pos4] = app.getWaypoint( app.imageFilter( pos4BIN, {"dilate", 20; "erode", 2; "areaopen",10} ));

            app.path = [posInit; pos1; pos2; pos3; pos4];

            % =========================================================
            %                   WPs 1
            % =========================================================
            possibleWP1 = [
                pos1(1) - app.OffsetWPEditField.Value, pos1(2);          % 1. Esquerda
                pos1(1) + app.OffsetWPEditField.Value, pos1(2);          % 2. Direita
                pos1(1),          pos1(2) + app.OffsetWPEditField.Value; % 3. Cima
                pos1(1),          pos1(2) - app.OffsetWPEditField.Value  % 4. Baixo
            ];

            % =========================================================
            %                   WPs 2
            % =========================================================
            possibleWP2 = [
                pos2(1) - app.OffsetWPEditField.Value, pos2(2);          % 1. Esquerda
                pos2(1) + app.OffsetWPEditField.Value, pos2(2);          % 2. Direita
                pos2(1),          pos2(2) + app.OffsetWPEditField.Value; % 3. Cima
                pos2(1),          pos2(2) - app.OffsetWPEditField.Value  % 4. Baixo
            ];

            % =========================================================
            %                   WPs 3
            % =========================================================
            possibleWP3 = [
                pos3(1) - app.OffsetWPEditField.Value, pos3(2);          % 1. Esquerda
                pos3(1) + app.OffsetWPEditField.Value, pos3(2);          % 2. Direita
                pos3(1),          pos3(2) + app.OffsetWPEditField.Value; % 3. Cima
                pos3(1),          pos3(2) - app.OffsetWPEditField.Value  % 4. Baixo
            ];

            % =========================================================
            %                   WPs 4
            % =========================================================
            possibleWP4 = [
                pos4(1) - app.OffsetWPEditField.Value, pos4(2);          % 1. Esquerda
                pos4(1) + app.OffsetWPEditField.Value, pos4(2);          % 2. Direita
                pos4(1),          pos4(2) + app.OffsetWPEditField.Value; % 3. Cima
                pos4(1),          pos4(2) - app.OffsetWPEditField.Value  % 4. Baixo
            ];
    
            app.basePossiblities = [possibleWP1; possibleWP2; possibleWP3; possibleWP4];
            

%% =======================================================================
            meter2px = (app.OffsetWPEditField.Value * 1000) * app.ratio;
        
            % =========================================================
            %                   WPs Px1 (Cálculo Visual)
            % =========================================================
            possibleWP1Px = [
                pos1Px(1) - meter2px, pos1Px(2);          % 1. Esquerda
                pos1Px(1) + meter2px, pos1Px(2);          % 2. Direita
                pos1Px(1),            pos1Px(2) + meter2px; % 3. Cima (Em imagem +Y é para baixo)
                pos1Px(1),            pos1Px(2) - meter2px  % 4. Baixo
            ];
        
            % =========================================================
            %                   WPs Px2
            % =========================================================
            possibleWP2Px = [
                pos2Px(1) - meter2px, pos2Px(2);
                pos2Px(1) + meter2px, pos2Px(2);
                pos2Px(1),            pos2Px(2) + meter2px;
                pos2Px(1),            pos2Px(2) - meter2px
            ];
        
            % =========================================================
            %                   WPs Px3
            % =========================================================
            possibleWP3Px = [
                pos3Px(1) - meter2px, pos3Px(2);
                pos3Px(1) + meter2px, pos3Px(2);
                pos3Px(1),            pos3Px(2) + meter2px;
                pos3Px(1),            pos3Px(2) - meter2px
            ];
        
            % =========================================================
            %                   WPs Px4
            % =========================================================
            possibleWP4Px = [
                pos4Px(1) - meter2px, pos4Px(2);
                pos4Px(1) + meter2px, pos4Px(2);
                pos4Px(1),            pos4Px(2) + meter2px;
                pos4Px(1),            pos4Px(2) - meter2px
            ];
        
            % --- PLOT POS 1 ---
            imshow(image, "Parent", app.Pos1);
            hold(app.Pos1, 'on');
            % Desenha os 4 pontos possíveis (Azul asterisco)
            plot(app.Pos1, possibleWP1Px(:,1), possibleWP1Px(:,2), 'b*', 'MarkerSize', 10, 'LineWidth', 2);
            % Desenha o centro (Vermelho cruz)
            plot(app.Pos1, pos1Px(1), pos1Px(2), 'r+', 'MarkerSize', 15, 'LineWidth', 2);
            hold(app.Pos1, 'off');
        
            % --- PLOT POS 2 ---
            imshow(image, "Parent", app.Pos2);
            hold(app.Pos2, 'on');
            % CORREÇÃO: Usar possibleWP2Px e pos2Px
            plot(app.Pos2, possibleWP2Px(:,1), possibleWP2Px(:,2), 'b*', 'MarkerSize', 10, 'LineWidth', 2);
            plot(app.Pos2, pos2Px(1), pos2Px(2), 'r+', 'MarkerSize', 15, 'LineWidth', 2);
            hold(app.Pos2, 'off');
        
            % --- PLOT POS 3 ---
            imshow(image, "Parent", app.Pos3);
            hold(app.Pos3, 'on');
            % CORREÇÃO: Usar possibleWP3Px e pos3Px
            plot(app.Pos3, possibleWP3Px(:,1), possibleWP3Px(:,2), 'b*', 'MarkerSize', 10, 'LineWidth', 2);
            plot(app.Pos3, pos3Px(1), pos3Px(2), 'r+', 'MarkerSize', 15, 'LineWidth', 2);
            hold(app.Pos3, 'off');
        
            % --- PLOT POS 4 ---
            imshow(image, "Parent", app.Pos4);
            hold(app.Pos4, 'on');
            % CORREÇÃO: Usar possibleWP4Px e pos4Px
            plot(app.Pos4, possibleWP4Px(:,1), possibleWP4Px(:,2), 'b*', 'MarkerSize', 10, 'LineWidth', 2);
            plot(app.Pos4, pos4Px(1), pos4Px(2), 'r+', 'MarkerSize', 15, 'LineWidth', 2);
            hold(app.Pos4, 'off');
        end

        % Button pushed function: MoveSPButton
        function MoveSPButtonPushed(app, event)
            app.moveRobotSP();
        end

        % Button pushed function: LoadSnapshotButton
        function LoadSnapshotButtonPushed(app, event)
            imagePath = "./capturas_ipcam/" + app.ImageDropDown.Value + ".jpg";
            app.testSnapshot = imread(imagePath);
            imshow(app.testSnapshot, "Parent", app.Preview);
            imshow(app.toBIN(app.testSnapshot, 'hsv', [app.HSlider.Value, ...
                                                        app.SSlider.Value ...
                                                        app.VSlider.Value]), ...
                                                        "Parent", app.HSVMask);
            imshow(rgb2hsv(app.testSnapshot), "Parent", app.HSVView);
        end

        % Button pushed function: SaveSnapshotButton
        function SaveSnapshotButtonPushed(app, event)
            pasta = 'capturas_ipcam';
            if ~exist(pasta, 'dir')
                mkdir(pasta);
            end
    
            timestamp = datestr(now, 'yyyy-mm-dd_HH-MM-SS');
            nome_ficheiro = fullfile(pasta, ['arena_' timestamp '.jpg']);
            imwrite(app.testSnapshot, nome_ficheiro);
    
            app.appNotify("info", "Snapshot", "📸 Foto capturada da IP cam: " + nome_ficheiro);
        end

        % Button pushed function: SaveColor2CSVButton
        function SaveColor2CSVButtonPushed(app, event)
            try
                app.saveCalibration();
                app.appNotify('success', 'Save Calibration Report', 'Save Successfuly');
            catch
                app.appNotify('error', 'Save Calibration Report', 'Save Failed');
            end % LoadColorCSVButtonPushed
        end

        % Button pushed function: LoadColorCSVButton
        function LoadColorCSVButtonPushed(app, event)
            try
                app.loadCalibration();
                app.refreshBaseColorMap();
                app.appNotify('success', 'Load Calibration Report', 'Loaded Successfuly');
            catch
                app.appNotify('error', 'Load Calibration Report', 'Load Failed');
            end % LoadColorCSVButtonPushed
        end

        % Button pushed function: MoveMPButton
        function MoveMPButtonPushed(app, event)
            app.moveRobotMP();
        end

        % Button pushed function: GetRobotPosButton
        function GetRobotPosButtonPushed(app, event)
            app.getRobotPos( app.cropArena( app.getSnapshot( ) ) );
        end

        % Button pushed function: GetSignSnapshotButton
        function GetSignSnapshotButtonPushed(app, event)
            app.analyzeTrafficSign();
        end

        % Button pushed function: Walk1mButton
        function Walk1mButtonPushed(app, event)
            % --- Script de Calibração de Coeficientes ---
            
            % 2. Inicializar estado da odometria em (0,0,0)
            pose = [0, 0, 0]; %
            
            % --- TESTE 1: Andar 1 Metro ---
            goalLinear = [1.0, 0.0]; % Alvo a 1 metro de distância no eixo X
            fprintf('A iniciar percurso de 1 metro...\n');
            
            pose = app.Drive2Point(goalLinear, pose);
            fprintf('Fim do percurso linear. Meça a distância na arena.\n');
            pause(2); % Pausa para observação
            
            % --- TESTE 2: Rotação de 180° (Inverter Marcha) ---
            % Ao apontar de volta para a origem (0,0), o robô deve rodar 180 graus
            goalRotate = [0.0, 0.0]; 
            fprintf('A iniciar rotação para inverter marcha (180°)...\n');
            
            % Usando a sua função com o parâmetro rotationOnly = true
            pose = app.Drive2Point(goalRotate, pose);

            
            pose = app.Drive2Point(goalLinear, pose);
            fprintf('Fim da rotação. O robô deve estar exatamente paralelo à linha inicial, mas virado ao contrário.\n');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.forColorDropDownLabel = uilabel(app.GridLayout);
            app.forColorDropDownLabel.Text = 'for Color';
            app.GInputCalibrationButton = uibutton(app.GridLayout, 'push');
            app.GInputCalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @GInputCalibrationButtonPushed, true);
            app.GInputCalibrationButton.Text = 'GInput Calibration';
            app.forColorDropDown = uidropdown(app.GridLayout);
            app.forColorDropDown.Items = {'Red', 'Yellow', 'Blue', 'Green', 'Black', 'White'};
            app.forColorDropDown.Value = 'Red';
            app.ApplyCalibrationButton = uibutton(app.GridLayout, 'push');
            app.ApplyCalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyCalibrationButtonPushed, true);
            app.ApplyCalibrationButton.Text = 'Apply Calibration';
            app.camIDDropDownLabel = uilabel(app.GridLayout);
            app.camIDDropDownLabel.Text = 'camID';
            app.AreaEditFieldLabel = uilabel(app.GridLayout);
            app.AreaEditFieldLabel.Text = 'Area';
            app.AreaEditField = uieditfield(app.GridLayout, 'numeric');
            app.AreaEditField.Value = 2500;
            app.CircularityEditFieldLabel = uilabel(app.GridLayout);
            app.CircularityEditFieldLabel.Text = 'Circularity';
            app.HSliderLabel = uilabel(app.GridLayout);
            app.HSliderLabel.Text = 'H';
            app.SSliderLabel = uilabel(app.GridLayout);
            app.SSliderLabel.Text = 'S';
            app.VSliderLabel = uilabel(app.GridLayout);
            app.VSliderLabel.Text = 'V';
            app.HueTolEditFieldLabel = uilabel(app.GridLayout);
            app.HueTolEditFieldLabel.Text = 'HueTol';
            app.SatTolEditFieldLabel = uilabel(app.GridLayout);
            app.SatTolEditFieldLabel.Text = 'SatTol';
            app.ValTolEditFieldLabel = uilabel(app.GridLayout);
            app.ValTolEditFieldLabel.Text = 'ValTol';
            app.ValTolEditField = uieditfield(app.GridLayout, 'numeric');
            app.ValTolEditField.Limits = [0 1];
            app.ValTolEditField.Value = 0.15;
            app.SatTolEditField = uieditfield(app.GridLayout, 'numeric');
            app.SatTolEditField.Limits = [0 1];
            app.SatTolEditField.Value = 0.15;
            app.HueTolEditField = uieditfield(app.GridLayout, 'numeric');
            app.HueTolEditField.Limits = [0 1];
            app.HueTolEditField.Value = 0.05;
            app.VSlider = uislider(app.GridLayout);
            app.VSlider.Limits = [0 1];
            app.VSlider.ValueChangedFcn = createCallbackFcn(app, @VSliderValueChanged, true);
            app.VSlider.Value = 0.5;
            app.HSlider = uislider(app.GridLayout);
            app.HSlider.Limits = [0 1];
            app.HSlider.ValueChangedFcn = createCallbackFcn(app, @HSliderValueChanged, true);
            app.HSlider.Value = 0.79;
            app.SSlider = uislider(app.GridLayout);
            app.SSlider.Limits = [0 1];
            app.SSlider.ValueChangedFcn = createCallbackFcn(app, @SSliderValueChanged, true);
            app.SSlider.Value = 0.3;
            app.EdgeDetectionMethodDropDownLabel = uilabel(app.GridLayout);
            app.EdgeDetectionMethodDropDownLabel.Text = 'Edge Detection Method';
            app.EdgeDetectionMethodDropDown = uidropdown(app.GridLayout);
            app.EdgeDetectionMethodDropDown.Items = {'Canny', 'approxcanny', 'Prewitt', 'Sobel', 'Roberts', 'log', 'zerocross'};
            app.EdgeDetectionMethodDropDown.Value = 'Canny';
            app.EdgeDetectionThresholdEditFieldLabel = uilabel(app.GridLayout);
            app.EdgeDetectionThresholdEditFieldLabel.Text = 'Edge Detection Threshold';
            app.EdgeDetectionThresholdEditField = uieditfield(app.GridLayout, 'numeric');
            app.EdgeDetectionThresholdEditField.Value = 0.3;
            app.HSVThresholdParametersLabel = uilabel(app.GridLayout);
            app.HSVThresholdParametersLabel.Text = 'HSV Threshold Parameters';
            app.SolidityEditField = uieditfield(app.GridLayout, 'numeric');
            app.EccentricityEditFieldLabel = uilabel(app.GridLayout);
            app.EccentricityEditFieldLabel.Text = 'Eccentricity';
            app.EccentricityEditField = uieditfield(app.GridLayout, 'numeric');
            app.ExtentEditFieldLabel = uilabel(app.GridLayout);
            app.ExtentEditFieldLabel.Text = 'Extent';
            app.ExtentEditField = uieditfield(app.GridLayout, 'numeric');
            app.MajorAxisLengthEditFieldLabel = uilabel(app.GridLayout);
            app.MajorAxisLengthEditFieldLabel.Text = 'MajorAxisLength';
            app.MajorAxisLengthEditField = uieditfield(app.GridLayout, 'numeric');
            app.MinorAxisLengthEditFieldLabel = uilabel(app.GridLayout);
            app.MinorAxisLengthEditFieldLabel.Text = 'MinorAxisLength';
            app.MinorAxisLengthEditField = uieditfield(app.GridLayout, 'numeric');
            app.CentroidEditFieldLabel = uilabel(app.GridLayout);
            app.CentroidEditFieldLabel.Text = 'Centroid';
            app.SolidityEditFieldLabel = uilabel(app.GridLayout);
            app.SolidityEditFieldLabel.Text = 'Solidity';
            app.CentroidEditField = uieditfield(app.GridLayout, 'numeric');
            app.CircularityEditField = uieditfield(app.GridLayout, 'numeric');
            app.BoundingBoxParametersLabel = uilabel(app.GridLayout);
            app.BoundingBoxParametersLabel.Text = 'BoundingBox Parameters';
            app.ApplicationControlsLabel = uilabel(app.GridLayout);
            app.ApplicationControlsLabel.Text = 'Application Controls';
            app.SavetoFileButton = uibutton(app.GridLayout, 'push');
            app.SavetoFileButton.Text = 'Save to File';
            app.TraficSignDropDownLabel = uilabel(app.GridLayout);
            app.TraficSignDropDownLabel.Text = 'Trafic Sign';
            app.TraficSignDropDown = uidropdown(app.GridLayout);
            app.TraficSignDropDown.Items = {'Right of Way', 'Forbid Traffic', 'Forbid Overtake', 'Temp Traffic Light', 'Temp Bump', 'Dead End', 'Danger Bump', 'Danger Ice', 'Obligation Roundabout', 'Obligation Turn Left', 'Obligation Turn Right'};
            app.TraficSignDropDown.Value = 'Right of Way';
            app.ConfidenceLevelEditFieldLabel = uilabel(app.GridLayout);
            app.ConfidenceLevelEditFieldLabel.Text = 'Confidence Level';
            app.ConfidenceLevelEditField = uieditfield(app.GridLayout, 'numeric');
            app.ConfidentButton = uibutton(app.GridLayout, 'push');
            app.ConfidentButton.Text = 'Confident';
            app.StartStopProcessingButton = uibutton(app.GridLayout, 'state');
            app.StartStopProcessingButton.ValueChangedFcn = createCallbackFcn(app, @StartStopProcessingButtonValueChanged, true);
            app.StartStopProcessingButton.Text = 'Start/Stop Processing';
            app.CameraLamp = uilamp(app.GridLayout);
            app.camIDDropDown = uidropdown(app.GridLayout);
            app.camIDDropDown.Items = {'1', '2', '3', '4'};
            app.camIDDropDown.ItemsData = {'1', '2', '3', '4'};
            app.camIDDropDown.Value = '1';
            app.IPCamConnectButton = uibutton(app.GridLayout, 'state');
            app.IPCamConnectButton.ValueChangedFcn = createCallbackFcn(app, @IPCamConnectButtonValueChanged, true);
            app.IPCamConnectButton.Text = 'IP Cam Connect';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.PreviewTab = uitab(app.TabGroup);
            app.PreviewTab.Title = 'Preview';
            app.Preview = uiaxes(app.PreviewTab);
            title(app.Preview, 'Preview')
            xlabel(app.Preview, 'X')
            ylabel(app.Preview, 'Y')
            zlabel(app.Preview, 'Z')
            app.HSVTopTab = uitab(app.TabGroup);
            app.HSVTopTab.Title = 'HSV Top';
            app.HSVView = uiaxes(app.HSVTopTab);
            title(app.HSVView, 'HSV')
            xlabel(app.HSVView, 'X')
            ylabel(app.HSVView, 'Y')
            zlabel(app.HSVView, 'Z')
            app.HSVMaskTab = uitab(app.TabGroup);
            app.HSVMaskTab.Title = 'HSV Mask';
            app.HSVMask = uiaxes(app.HSVMaskTab);
            title(app.HSVMask, 'HSV Mask')
            xlabel(app.HSVMask, 'X')
            ylabel(app.HSVMask, 'Y')
            zlabel(app.HSVMask, 'Z')
            app.MapTab = uitab(app.TabGroup);
            app.MapTab.Title = 'Map';
            app.MapView = uiaxes(app.MapTab);
            title(app.MapView, 'MAP View')
            xlabel(app.MapView, 'X')
            ylabel(app.MapView, 'Y')
            zlabel(app.MapView, 'Z')
            app.RobotPosTab = uitab(app.TabGroup);
            app.RobotPosTab.Title = 'RobotPos';
            app.RobotPos = uiaxes(app.RobotPosTab);
            title(app.RobotPos, 'RobotPos')
            xlabel(app.RobotPos, 'X')
            ylabel(app.RobotPos, 'Y')
            zlabel(app.RobotPos, 'Z')
            app.WaypointsTab = uitab(app.TabGroup);
            app.WaypointsTab.Title = 'Waypoints';
            app.Pos1 = uiaxes(app.WaypointsTab);
            title(app.Pos1, 'Pos1')
            xlabel(app.Pos1, 'X')
            ylabel(app.Pos1, 'Y')
            zlabel(app.Pos1, 'Z')
            app.Pos2 = uiaxes(app.WaypointsTab);
            title(app.Pos2, 'Pos2')
            xlabel(app.Pos2, 'X')
            ylabel(app.Pos2, 'Y')
            zlabel(app.Pos2, 'Z')
            app.Pos3 = uiaxes(app.WaypointsTab);
            title(app.Pos3, 'Pos3')
            xlabel(app.Pos3, 'X')
            ylabel(app.Pos3, 'Y')
            zlabel(app.Pos3, 'Z')
            app.Pos4 = uiaxes(app.WaypointsTab);
            title(app.Pos4, 'Pos4')
            xlabel(app.Pos4, 'X')
            ylabel(app.Pos4, 'Y')
            zlabel(app.Pos4, 'Z')
            app.PathTab = uitab(app.TabGroup);
            app.PathTab.Title = 'Path';
            app.PathView = uiaxes(app.PathTab);
            title(app.PathView, 'Path')
            xlabel(app.PathView, 'X')
            ylabel(app.PathView, 'Y')
            zlabel(app.PathView, 'Z')
            app.EdgeCutTab = uitab(app.TabGroup);
            app.EdgeCutTab.Title = 'EdgeCut';
            app.EdgeCut = uiaxes(app.EdgeCutTab);
            title(app.EdgeCut, 'EdgeCut')
            xlabel(app.EdgeCut, 'X')
            ylabel(app.EdgeCut, 'Y')
            zlabel(app.EdgeCut, 'Z')
            app.BoundBoxTab = uitab(app.TabGroup);
            app.BoundBoxTab.Title = 'BoundBox';
            app.BoundingBoxView = uiaxes(app.BoundBoxTab);
            title(app.BoundingBoxView, 'Bounding Box')
            xlabel(app.BoundingBoxView, 'X')
            ylabel(app.BoundingBoxView, 'Y')
            zlabel(app.BoundingBoxView, 'Z')
            app.ConfidenceTab = uitab(app.TabGroup);
            app.ConfidenceTab.Title = 'Confidence';
            app.Confidence = uiaxes(app.ConfidenceTab);
            title(app.Confidence, 'Title')
            xlabel(app.Confidence, 'X')
            ylabel(app.Confidence, 'Y')
            zlabel(app.Confidence, 'Z')
            app.RefmmEditFieldLabel = uilabel(app.GridLayout);
            app.RefmmEditFieldLabel.Text = 'Ref (mm)';
            app.RefmmEditField = uieditfield(app.GridLayout, 'numeric');
            app.RefmmEditField.Value = 150;
            app.MapCreationButton = uibutton(app.GridLayout, 'push');
            app.MapCreationButton.ButtonPushedFcn = createCallbackFcn(app, @MapCreationButtonPushed, true);
            app.MapCreationButton.Text = 'Map Creation';
            app.MapGenerationParametersLabel = uilabel(app.GridLayout);
            app.MapGenerationParametersLabel.Text = 'Map Generation Parameters';
            app.WaypointOrderLabel = uilabel(app.GridLayout);
            app.WaypointOrderLabel.Text = 'Waypoint Order';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '1';
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_2.Text = '2';
            app.Label_3 = uilabel(app.GridLayout);
            app.Label_3.Text = '3';
            app.Label_4 = uilabel(app.GridLayout);
            app.Label_4.Text = '4';
            app.WP_4 = uidropdown(app.GridLayout);
            app.WP_4.Items = {'Red', 'Green', 'Blue', 'Yellow'};
            app.WP_4.Value = 'Yellow';
            app.WP_3 = uidropdown(app.GridLayout);
            app.WP_3.Items = {'Red', 'Green', 'Blue', 'Yellow'};
            app.WP_3.Value = 'Blue';
            app.WP_2 = uidropdown(app.GridLayout);
            app.WP_2.Items = {'Red', 'Green', 'Blue', 'Yellow'};
            app.WP_2.Value = 'Green';
            app.WP_1 = uidropdown(app.GridLayout);
            app.WP_1.Items = {'Red', 'Green', 'Blue', 'Yellow'};
            app.WP_1.Value = 'Red';
            app.camIPEditFieldLabel = uilabel(app.GridLayout);
            app.camIPEditFieldLabel.Text = 'camIP';
            app.camIPEditField = uieditfield(app.GridLayout, 'text');
            app.camIPEditField.Value = '192.168.0.20';
            app.RobotParametersLabel = uilabel(app.GridLayout);
            app.RobotParametersLabel.Text = 'Robot Parameters';
            app.WheelRadiusEditFieldLabel = uilabel(app.GridLayout);
            app.WheelRadiusEditFieldLabel.Text = 'Wheel Radius';
            app.WheelRadiusEditField = uieditfield(app.GridLayout, 'numeric');
            app.WheelRadiusEditField.Value = 0.028;
            app.WheelBaseEditFieldLabel = uilabel(app.GridLayout);
            app.WheelBaseEditFieldLabel.Text = 'Wheel Base';
            app.WheelBaseEditField = uieditfield(app.GridLayout, 'numeric');
            app.WheelBaseEditField.Value = 0.17;
            app.toDropDownLabel = uilabel(app.GridLayout);
            app.toDropDownLabel.Text = 'to';
            app.toAdjustApplyDropDown = uidropdown(app.GridLayout);
            app.toAdjustApplyDropDown.Items = {'Ratio', 'Map', 'Base', 'Sign'};
            app.toAdjustApplyDropDown.Value = 'Ratio';
            app.ConnectRobotButton = uibutton(app.GridLayout, 'state');
            app.ConnectRobotButton.ValueChangedFcn = createCallbackFcn(app, @ConnectRobotButtonValueChanged, true);
            app.ConnectRobotButton.Text = 'ConnectRobot';
            app.CameraParametersLabel = uilabel(app.GridLayout);
            app.CameraParametersLabel.Text = 'Camera Parameters';
            app.CamSourceDropDownLabel = uilabel(app.GridLayout);
            app.CamSourceDropDownLabel.Text = 'Cam Source';
            app.CamSourceDropDown = uidropdown(app.GridLayout);
            app.CamSourceDropDown.Items = {'IP', 'USB', 'Image'};
            app.CamSourceDropDown.Value = 'IP';
            app.GetRatioButton = uibutton(app.GridLayout, 'push');
            app.GetRatioButton.ButtonPushedFcn = createCallbackFcn(app, @GetRatioButtonPushed, true);
            app.GetRatioButton.Text = 'Get Ratio';
            app.GenSingleWPButton = uibutton(app.GridLayout, 'push');
            app.GenSingleWPButton.ButtonPushedFcn = createCallbackFcn(app, @GenSingleWPButtonPushed, true);
            app.GenSingleWPButton.Text = 'Gen Single WP';
            app.MoveSPButton = uibutton(app.GridLayout, 'push');
            app.MoveSPButton.ButtonPushedFcn = createCallbackFcn(app, @MoveSPButtonPushed, true);
            app.MoveSPButton.Text = 'MoveSP';
            app.GenMultipleWPButton = uibutton(app.GridLayout, 'push');
            app.GenMultipleWPButton.ButtonPushedFcn = createCallbackFcn(app, @GenMultipleWPButtonPushed, true);
            app.GenMultipleWPButton.Text = 'Gen Multiple WP';
            app.RobotControlLabel = uilabel(app.GridLayout);
            app.RobotControlLabel.Text = 'Robot Control';
            app.GetSnapshotButton = uibutton(app.GridLayout, 'push');
            app.GetSnapshotButton.ButtonPushedFcn = createCallbackFcn(app, @GetSnapshotButtonPushed, true);
            app.GetSnapshotButton.Text = 'Get Snapshot';
            app.USBCamConnectButton = uibutton(app.GridLayout, 'state');
            app.USBCamConnectButton.ValueChangedFcn = createCallbackFcn(app, @USBCamConnectButtonValueChanged, true);
            app.USBCamConnectButton.Text = 'USB Cam Connect';
            app.SaveSnapshotButton = uibutton(app.GridLayout, 'push');
            app.SaveSnapshotButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSnapshotButtonPushed, true);
            app.SaveSnapshotButton.Text = 'Save Snapshot';
            app.LoadSnapshotButton = uibutton(app.GridLayout, 'push');
            app.LoadSnapshotButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSnapshotButtonPushed, true);
            app.LoadSnapshotButton.Text = 'Load Snapshot';
            app.ImageDropDownLabel = uilabel(app.GridLayout);
            app.ImageDropDownLabel.Text = 'Image';
            app.ImageDropDown = uidropdown(app.GridLayout);
            app.ImageDropDown.Items = {'Robot', 'Bases', 'Bases2', 'Bases3', 'Robot&Bases', 'Robot&Bases2', 'Robot&Bases3', 'BaseLim', 'Robot&BaseLim'};
            app.ImageDropDown.Value = 'Robot';
            app.userEditFieldLabel = uilabel(app.GridLayout);
            app.userEditFieldLabel.Text = 'user';
            app.userEditField = uieditfield(app.GridLayout, 'text');
            app.userEditField.Value = 'admin';
            app.passwordEditFieldLabel = uilabel(app.GridLayout);
            app.passwordEditFieldLabel.Text = 'password';
            app.passwordEditField = uieditfield(app.GridLayout, 'text');
            app.passwordEditField.Value = '12345';
            app.SaveColor2CSVButton = uibutton(app.GridLayout, 'push');
            app.SaveColor2CSVButton.ButtonPushedFcn = createCallbackFcn(app, @SaveColor2CSVButtonPushed, true);
            app.SaveColor2CSVButton.Text = 'Save Color2CSV';
            app.LoadColorCSVButton = uibutton(app.GridLayout, 'push');
            app.LoadColorCSVButton.ButtonPushedFcn = createCallbackFcn(app, @LoadColorCSVButtonPushed, true);
            app.LoadColorCSVButton.Text = 'Load ColorCSV';
            app.FileNameEditFieldLabel = uilabel(app.GridLayout);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.GridLayout, 'text');
            app.FileNameEditField.Value = 'nightCalibv2';
            app.OffsetWPEditFieldLabel = uilabel(app.GridLayout);
            app.OffsetWPEditFieldLabel.Text = 'Offset WP';
            app.OffsetWPEditField = uieditfield(app.GridLayout, 'numeric');
            app.OffsetWPEditField.Value = 0.4;
            app.MoveMPButton = uibutton(app.GridLayout, 'push');
            app.MoveMPButton.ButtonPushedFcn = createCallbackFcn(app, @MoveMPButtonPushed, true);
            app.MoveMPButton.Text = 'MoveMP';
            app.NumNodesEditFieldLabel = uilabel(app.GridLayout);
            app.NumNodesEditFieldLabel.Text = 'NumNodes';
            app.NumNodesEditField = uieditfield(app.GridLayout, 'numeric');
            app.NumNodesEditField.Value = 800;
            app.connDistEditFieldLabel = uilabel(app.GridLayout);
            app.connDistEditFieldLabel.Text = 'connDist';
            app.connDistEditField = uieditfield(app.GridLayout, 'numeric');
            app.connDistEditField.Value = 0.3;
            app.LinearVelocityEditFieldLabel = uilabel(app.GridLayout);
            app.LinearVelocityEditFieldLabel.Text = 'LinearVelocity';
            app.LinearVelocityEditField = uieditfield(app.GridLayout, 'numeric');
            app.LinearVelocityEditField.Value = 0.1;
            app.MaxAngVelocityEditFieldLabel = uilabel(app.GridLayout);
            app.MaxAngVelocityEditFieldLabel.Text = 'MaxAngVelocity';
            app.MaxAngVelocityEditField = uieditfield(app.GridLayout, 'numeric');
            app.MaxAngVelocityEditField.Value = 1.2;
            app.LookAheadEditFieldLabel = uilabel(app.GridLayout);
            app.LookAheadEditFieldLabel.Text = 'Look Ahead';
            app.LookAheadEditField = uieditfield(app.GridLayout, 'numeric');
            app.LookAheadEditField.Value = 0.3;
            app.DilateMapEditFieldLabel = uilabel(app.GridLayout);
            app.DilateMapEditFieldLabel.Text = 'DilateMap';
            app.DilateMapEditField = uieditfield(app.GridLayout, 'numeric');
            app.DilateMapEditField.Value = 30;
            app.GetRobotPosButton = uibutton(app.GridLayout, 'push');
            app.GetRobotPosButton.ButtonPushedFcn = createCallbackFcn(app, @GetRobotPosButtonPushed, true);
            app.GetRobotPosButton.Text = 'Get RobotPos';
            app.MethodDropDownLabel = uilabel(app.GridLayout);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.GridLayout);
            app.MethodDropDown.Items = {'PurePursuit', 'Drive2Point'};
            app.MethodDropDown.Value = 'Drive2Point';
            app.GetSignSnapshotButton = uibutton(app.GridLayout, 'push');
            app.GetSignSnapshotButton.ButtonPushedFcn = createCallbackFcn(app, @GetSignSnapshotButtonPushed, true);
            app.GetSignSnapshotButton.Text = 'Get Sign Snapshot';
            app.Walk1mButton = uibutton(app.GridLayout, 'push');
            app.Walk1mButton.ButtonPushedFcn = createCallbackFcn(app, @Walk1mButtonPushed, true);
            app.Walk1mButton.Text = 'Walk 1m';
            app.KvEditField_2Label = uilabel(app.GridLayout);
            app.KvEditField_2Label.Text = 'Kv';
            app.KvEditField = uieditfield(app.GridLayout, 'numeric');
            app.KvEditField.Value = 2;
            app.KhEditFieldLabel = uilabel(app.GridLayout);
            app.KhEditFieldLabel.Text = 'Kh';
            app.KhEditField = uieditfield(app.GridLayout, 'numeric');
            app.KhEditField.Value = 5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TP2_GUI_v5_3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end